classdef appMonitorLicorEC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrometSiteInstrumentsUIFigure  matlab.ui.Figure
        LICORECMonitorLabel  matlab.ui.control.Label
        UpdateAllButton      matlab.ui.control.Button
        LastdataupdatePanel  matlab.ui.container.Panel
        LastUpdateLabel      matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        Site_1               matlab.ui.container.Tab
    end

    % appMonitorLicorEC(projectPath)
    % 
    %   - Collects data directly from LI-7x00 instruments over Ethernet
    %   - The instruments should be set to output all data over Ethernet at 1Hz 
    %     (not faster and not slower than this)
    %   - Calculates half houry statistics. Stores raw data and database files 
    %     in the usual "Sites" and "Database" folders.
    %   - requires the input argument projectPath. This path should point
    %     to the main project folder (the folder containing "Sites" and "Database" folders.
    %     On UQAM    server: projectPath = "c:\Projects\UQAM"
    %     On vinimet server: projectPath = "p:\"
    %     On PAOA001 server: projectPath = "d:\"
    %     (for testing: server: projectPath = "d:\junk")
    %   - The program expects access to (has to have on the Matlab path) a "pretend_configYAML.m" file containing all input parameters.
    %     In the future this will be replaced by a proper YAML file ("projectUQAM_config.yaml")
    %     that will be read by read
    % 
    %    Example of a minimum pretend_configYAML.m file:
    %       function structProject = pretend_configYAML(projectPath)
    %       % This function will be replaced with a single config.yml file
    %       % in the projectPath folder that the parent function will read.
    %       %
    %       % To simplify writing of YAML config files and reduce cutting and pasting
    %       % errors, we could write a Matlab GUI to create the initial YAML using the code
    %       % below. The resulting YAML can then be
    %       % edited by either the same GUI or by the user using a text editor.
    %       % 
    %       % Here is the matlab structure structProject that could be saved
    %       % as YAML file using yaml.dumpFile function.
    %       %-------- start yaml ---------------------
    %       projectName = '';               % needs to be an empty string for vinimet and PAOA001. 
    %                                       % For all new sites should be the actual project name
    %                                       % Examples: 
    %                                       % UAQM:
    %                                       %    projectPath = 'c:\Projects'
    %                                       %    projectName = 'UQAM'
    %                                       % McGill's version of 'Micromet':
    %                                       %    projectPath = 'c:\Projects'
    %                                       %    projectName = 'Micromet'
    % 
    %       structProject.projectName   = projectName;
    %       structProject.path          = fullfile(projectPath,structProject.projectName);
    %       structProject.databasePath  = fullfile(structProject.path,'Database');
    %       structProject.sitesPath     = fullfile(structProject.path,'Sites');
    %       structProject.matlabPath    = fullfile(structProject.path,'Matlab');
    % 
    %       siteID = 'BB1';
    %       structProject.sites.(siteID).siteIP = "173.181.138.49";

    % Revisions
    %

    properties (Access = private)
        aVariable           % Description
        timerMain           % main timer object that initiates data collection
        siteInfo            % data structure with site names and IP addresses (loaded from a yml file)
        siteData            % data structure with all data for the current 30-min period
        sampleNum = 0;      % current data point being acquired
        tvLastSample = 0;   % datenum of the last data point  
        samplingPeriodSec   % sampling period
        outputFileNameHF = "monitorSiteLI7x00_HFdata.%s.mat";      %
        outputPathHF      % High frequency data path name
        outputFileNameHH = "monitorSiteLI7x00_hhour.%s.mat";      %
        outputPathHH      % Half hour summaries path name
        outputPathDB      % output path for the data base
        delayCalling = 20 % if running tests on my PC offset the timer by 20 seconds to avoid 
                          % overlapping the calls between my PC and the main server at vinimet
    end
    
    methods (Access = private)
        %================================
        % Download data, update all tabs
        %================================
        function results = downloadData(app,siteName,siteAddress,sitePortNum)
            arg_default('sitePortNum',7200);
            cntSites = find(strcmpi({app.siteInfo(:).name},siteName));
            currentTab = app.siteInfo(cntSites).tabHandle;
            try
                [dataOut,cError,allData] = fr_read_TCP_LI7x00_data(siteAddress,sitePortNum,5);
                app.siteData(app.sampleNum).(siteName) = dataOut; 

                if isempty(cError)
                    currentTab.ForegroundColor = [0 0 0];
                    currentData = app.siteData(app.sampleNum).(siteName);
                    allFields = fieldnames(currentData);
                    outputString = sprintf('Sample #%d for this half hour.\nCollected at: %s\n\n',app.sampleNum,datestr(currentData.TimeVector,31));
                    for cntFields = 1:length(allFields)
                        currentFieldName = char(allFields(cntFields));
                        dataValue = app.siteData(app.sampleNum).(siteName).(currentFieldName);
                        % test if any data value is out of range
                        errorIndicator = '';
                        switch currentFieldName
                            case 'Diag'                                
                                if dataValue == 255 && isempty(dataOut.flowDrive)
                                    % case LI7500, diagnostic value.
                                    errorIndicator = ' *';
                                    currentTab.ForegroundColor = [1 1 0];
                                elseif dataValue < 8190 && ~isempty(dataOut.flowDrive)
                                    % case LI7200, low diagnostic value
                                    errorIndicator = ' *';
                                    currentTab.ForegroundColor = [1 1 0];                                
                                end
                            case 'flowRate'
                                if dataValue < 13
                                    errorIndicator = ' *';
                                    currentTab.ForegroundColor = [1 1 0];
                                end
                            case 'flowDrive'
                                if dataValue > 90
                                    errorIndicator = '>90%';
                                    currentTab.ForegroundColor = [1 1 0];
                                end
                            case {'tempOut','tempIn'}
                                if ~isempty(dataValue) && ~isempty(dataOut.tempOut) && abs(dataOut.tempOut-dataOut.tempIn) > 3
                                    errorIndicator = 'Tin-Tout>3';
                                    currentTab.ForegroundColor = [1 1 0];
                                end
                            case 'signalStrength7700'
                                if dataValue < 20
                                    errorIndicator = ' <20';
                                    currentTab.ForegroundColor = [1 1 0];
                                end          
                            case 'Phead'
                                if dataValue < -3
                                    errorIndicator = ' <-3';
                                    currentTab.ForegroundColor = [1 1 0];
                                end                                     
                        end
                        outputString = [outputString sprintf('%25s = %12.2f %s\n',currentFieldName,dataValue,errorIndicator)];
                    end
                    app.siteInfo(cntSites).textArea1.Value = outputString;
                else
                    currentTab.ForegroundColor = [1 0 0];
                    app.siteInfo(cntSites).textArea1.Value = [cError allData];                    
                end              

            catch ME
                currentTab.ForegroundColor = [1 0 0];
                disp(ME);
                fprintf('*ERROR for %s site.\n',siteName);
                %disp(dataOut)
            end
            drawnow
        end
        
       
        function mainTimerCallback(app, ~, event)
            UpdateAllButtonPushed(app,event)
        end
        
        % ==================================================
        % halfHourEvent
        %
        % Calculates stats, stores HF and HH data
        %===================================================
        function halfHourEvent(app,currentHHour)          
            % Save current data into dataHF
            dataHF = app.siteData;
            
            % save high frequency data using currentHHour (need an
            % end-of-period time stamp)
            fileFolder = fullfile(app.outputPathHF,datestr(currentHHour,'yyyymmdd'));
            if ~exist(fileFolder,"dir")
                mkdir(fileFolder)                     
            end
            fileName = sprintf(app.outputFileNameHF,datestr(currentHHour,'yyyymmddHHMM'));
            filePathHF = fullfile(fileFolder,fileName);
            save(filePathHF,'dataHF')
            
            % clean data buffer
            app.siteData = [];
            app.sampleNum = 0;            
            
            try
                %% calculate hhourly stats            
                % cycle through all sites
                strAllSiteNames = fieldnames(dataHF);             
                for cntSiteName = 1:length(strAllSiteNames)
                    strSiteName = char(strAllSiteNames{cntSiteName});
                    % pick only site names, skip TimeVector
                    if ~strcmpi(strSiteName,"TimeVector")     
                        % now get all variables that need averaging
                        strAllVariables = fieldnames(dataHF(1).(strSiteName));
                        for cntVarName = 1:length(strAllVariables)
                            strVarName = char(strAllVariables{cntVarName});
                            dataIn = get_stats_field_fast(dataHF,[strSiteName '.' strVarName]);                        
                            if ~strcmpi(strVarName,"TimeVector")
                                tmpStats.(strSiteName).(strVarName).avg = mean(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).max = max(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).min = min(dataIn(~isnan(dataIn)));
                                tmpStats.(strSiteName).(strVarName).std = std(dataIn(~isnan(dataIn)));
                            else
                                % Store the last time stamp collected from the instrument
                                % as datenum, but store the time only (no date)
                                tmpStats.(strSiteName).Time = dataIn(end)-floor(dataIn(end));
                                % Store also the TimeVector 
                                tmpStats.(strSiteName).TimeVector = currentHHour;
                                % Calculate stats on the sampling rate (how often is data collected)
                                tmpSampleRate = diff(dataIn *24*3600);
                                if ~isempty(tmpSampleRate)
                                    tmpStats.(strSiteName).SampleRate.avg = mean(tmpSampleRate); %#ok<*AGROW> 
                                    tmpStats.(strSiteName).SampleRate.max = max(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.min = min(tmpSampleRate);
                                    tmpStats.(strSiteName).SampleRate.std = std(tmpSampleRate);
                                else
                                    tmpStats.(strSiteName).SampleRate.avg = NaN; 
                                    tmpStats.(strSiteName).SampleRate.max = NaN;
                                    tmpStats.(strSiteName).SampleRate.min = NaN;
                                    tmpStats.(strSiteName).SampleRate.std = NaN;      
                                end
                            end
                        end
                        tmpStats.(strSiteName).numOfSamples = length(dataIn);
                    end
                end
                % Add TimeVector to the main structure
                tmpStats.TimeVector = currentHHour;
                % save half hourly stats using currentHHour (need an
                % end-of-period time stamp). 
                fileName = sprintf(app.outputFileNameHH,datestr(currentHHour,'yyyymmdd'));
                filePathHH = fullfile(app.outputPathHH,fileName);
                % check if the file with the same name already exists there
                if exist(filePathHH,'file')
                    load(filePathHH,'Stats');
                    numRec = length(Stats);
                else
                    numRec = 0;
                end
                numRec = numRec+1;
                % append the new record to the existing ones
                Stats(numRec) = tmpStats;
                % save hhour stats
                save(filePathHH,'Stats');
                % also save the data into database (only the last record)
                Stats = tmpStats;
                % Collect all site names
                allSites = {};
                for cntSite = 1:length(app.siteInfo)
                    allSites{cntSite} = app.siteInfo(cntSite).name;
                end
                %allSites = {'BB1','BB2','DSM','RBM','HOGG','YOUNG','OHM'};
                for cntSite = 1:length(allSites)
                    currentSite = allSites{cntSite};
                    % when processing data exclude all the sites except the currentSite.
                    excludeSites = setdiff(allSites,currentSite);
                    if strcmpi(currentSite,'BB1')
                        siteName = 'BB';
                    else
                        siteName = currentSite;
                    end
                    % append the year to outputPathDB
                    dbasePath = fullfile(app.outputPathDB,datestr(currentHHour,'yyyy'));
                    [~,~,~, ~] = ...
                        db_new_eddy(Stats,[],...
                                    fullfile(dbasePath,siteName,'monitorSites'),...
                                    0,excludeSites,[],NaN); 
                end
            catch ME
                % If an error, skip hhour calculations
                % Display the error message for troubleshooting purposes.
                disp(ME.message);
            end       
        end % function halfHourEvent
    end % methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, projectPath)
            
            % Return if project path is not defined
            if ~exist("projectPath","var") || isempty(projectPath)
                msgbox("Please set the input argument: projectPath!","non-modal");
                MicrometSiteInstrumentsUIFigureCloseRequest(app)
            end
            % Check which PC is app running on (ENG0x or vinimet) and

            if strcmpi(fullfile(projectPath),fullfile('d:\junk'))
                app.outputPathHF = fullfile(projectPath,'monitorSiteLI7x00/data');
                app.outputPathHH = fullfile(projectPath,'monitorSiteLI7x00/hhour');
                app.outputPathDB = fullfile(projectPath,'monitorSiteLI7x00/database');
                app.delayCalling = 20;      % offset calling sites by 20 seconds to avoid overlap with vinimet
                app.samplingPeriodSec = 60; % faster sampling during app design
            else
                app.outputPathHF = fullfile(projectPath,'Sites/Common/monitorSiteLI7x00/data');
                app.outputPathHH = fullfile(projectPath,'Sites/Common/monitorSiteLI7x00/hhour');
                app.outputPathDB = fullfile(projectPath,'Database');
                app.delayCalling = 0;
                app.samplingPeriodSec = 180;
            end
            %app.Label_15.Text = app.outputPathDB; 

            % Import site names and IP addresses from a YAML file
            structProject = pretend_configYAML(projectPath);

            % 
            % app.siteInfo(1).name = 'BB1';
            % app.siteInfo(1).IPaddress = "173.181.138.49";            
            % app.siteInfo(2).name = 'BB2';
            % app.siteInfo(2).IPaddress = "96.1.106.219";                        
            % app.siteInfo(3).name = 'DSM';
            % app.siteInfo(3).IPaddress = "173.181.139.5";                                    
            % app.siteInfo(4).name = 'RBM';
            % app.siteInfo(4).IPaddress = "173.181.139.4";                        
            % app.siteInfo(5).name = 'HOGG';
            % app.siteInfo(5).IPaddress = "68.182.37.103";                        
            % app.siteInfo(6).name = 'YOUNG';
            % app.siteInfo(6).IPaddress = "68.182.37.116";                        
            % app.siteInfo(7).name = 'OHM';
            % app.siteInfo(7).IPaddress = "68.179.19.212";            
            % 
            % Cycle through all sites
            %   - load up the siteInfo from YAML file
            %   - Name all tabs
            allSiteNames = fieldnames(structProject.sites);
            for cntSites = 1:length(allSiteNames)
                % Load site names and IP addresses
                app.siteInfo(cntSites).name = char(allSiteNames(cntSites));
                app.siteInfo(cntSites).IPaddress = structProject.sites.(app.siteInfo(cntSites).name).siteIP;
                % create a new tab
                if cntSites == 1
                    currentTab = app.TabGroup.Children;
                else
                    currentTab = uitab(app.TabGroup,'Title',app.siteInfo(cntSites).name);
                end
                app.siteInfo(cntSites).tabHandle = currentTab;
                currentTab.Title = app.siteInfo(cntSites).name;
                tabPos = currentTab.Position;
                app.siteInfo(cntSites).textArea1 = uitextarea(currentTab,Position=[tabPos(1:2)+2 350 380],Editable = 'off',Fontname = 'Consolas');
                app.siteInfo(cntSites).textArea1.HorizontalAlignment = 'left';
                app.siteInfo(cntSites).textArea1.Value = [sprintf('%30s = %6.2f\n','co2',555-cntSites) ,sprintf('%30s = %6.2f\n','SignalStrength',100-cntSites/5)];
                app.siteInfo(cntSites).textArea1.FontColor = [0 0 0];

            end
            
            % updated data once to populate the table
            UpdateAllButtonPushed(app)
            
            % stop the timer function if already running
            t = timerfind('Name','siteUpdateTimer');
            if ~isempty(t)
                stop(t)
                delete(t)
            end
            
            % calculate delay time so the sampling time can be aligned to
            % sample at 0 seconds into samplingPeriodSec
            tv = now;
            samplesPerDay = 24*3600/app.samplingPeriodSec;         
            nextPeriod = ceil(tv*samplesPerDay)/samplesPerDay;
            delayTime = round((nextPeriod-tv)*24*3600) + app.delayCalling;  % delay time in seconds
            % create a timer that will update data every
            % 5 minutes (300s). 
            % to speedup debugging, run the timer faster (once every 60 seconds)
            app.timerMain = timer( "timerfcn",@app.mainTimerCallback,...
                                   "StartDelay",delayTime,...
                                   "Period",app.samplingPeriodSec,...
                                   "ExecutionMode",'fixedRate',...
                                   "Name",'siteUpdateTimer');
            % then start the timerMain
            start(app.timerMain)
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)

            % Check if this is a new 30-min period and,
            % if it is, store the collected data (if there is any)
            if app.sampleNum > 0
                % check if this is a new 30min period
                lastHHour = fix(app.siteData(app.sampleNum).TimeVector*48)/48;
                currentHHour = fix(now*48)/48;
                if lastHHour < currentHHour
                    % calculate hhourly stats
                    % calculate stats for all variables for each site
                    % store high frequency and half-hourly values
                    halfHourEvent(app,currentHHour)
                end
                
            end
                
            % There is a new data sample being collected. Increment counter
            app.sampleNum = app.sampleNum + 1;
            app.siteData(app.sampleNum).TimeVector = now;
            app.LastUpdateLabel.Text = datestr(now,31);

            % Gray out all the tabs to indicate that an update is in progress
            for cntSites = 1:length(app.siteInfo)
                currentTab = app.siteInfo(cntSites).tabHandle;
                currentTab.ForegroundColor = [0.5 0.5 0.5];
            end
            % download data a update tabs
            for cntSites = 1:length(app.siteInfo)
                downloadData(app,app.siteInfo(cntSites).name,app.siteInfo(cntSites).IPaddress);
            end
            
        end

        % Close request function: MicrometSiteInstrumentsUIFigure
        function MicrometSiteInstrumentsUIFigureCloseRequest(app, event)
            if isobject("app.timerMain")
                stop(app.timerMain)
                delete(app.timerMain)
                clear app.timerMain
            end
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrometSiteInstrumentsUIFigure = uifigure('Visible', 'off');
            app.MicrometSiteInstrumentsUIFigure.Name = 'Micromet Site Instruments';
            app.MicrometSiteInstrumentsUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrometSiteInstrumentsUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MicrometSiteInstrumentsUIFigure);
            app.Site_1 = uitab(app.TabGroup);
            app.LastdataupdatePanel = uipanel(app.MicrometSiteInstrumentsUIFigure);
            app.LastdataupdatePanel.Title = 'Last data update:';
            app.LastUpdateLabel = uilabel(app.LastdataupdatePanel);
            app.LastUpdateLabel.Text = '';
            app.UpdateAllButton = uibutton(app.MicrometSiteInstrumentsUIFigure, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Enable = 'off';
            app.UpdateAllButton.Visible = 'off';
            app.UpdateAllButton.Text = 'Update All';
            app.LICORECMonitorLabel = uilabel(app.MicrometSiteInstrumentsUIFigure);
            app.LICORECMonitorLabel.Text = 'LICOR EC Monitor';
            % Show the figure after all components are created
            app.MicrometSiteInstrumentsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMonitorLicorEC(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.MicrometSiteInstrumentsUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.MicrometSiteInstrumentsUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrometSiteInstrumentsUIFigure)
        end
    end
end