classdef guiPickFile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TraceSelectionUIFigure  matlab.ui.Figure
        FolderEditFieldLabel    matlab.ui.control.Label
        FolderEditField         matlab.ui.control.EditField
        BrowseButton            matlab.ui.control.Button
        TraceDropDownLabel      matlab.ui.control.Label
        TraceDropDown           matlab.ui.control.DropDown
        OKButton                matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        panelToPlot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.panelToPlot = mainapp.currentPanel;
            colordef white
            % make sure that this figure opens on the top of the mainapp
            % check where the mainapp is
            mainappPosition = get(mainapp.TraceBrowserUIFigure,'position');
            mainappCenter = [ mainappPosition(1)+mainappPosition(3)/2 mainappPosition(2)+mainappPosition(4)/2 ];
            appPosition = get(app.TraceSelectionUIFigure,'Position');
            appPosition = [mainappCenter(1)-appPosition(3)/2 mainappCenter(2)-appPosition(4)/2 appPosition(3:4)];
            set(app.TraceSelectionUIFigure,'Position',appPosition);
            
            % Update UI with input values
            app.FolderEditField.Value = deblank(app.CallingApp.Folder(app.panelToPlot,:));
            
            a=dir(app.FolderEditField.Value);                         % Obtains the contents of the selected path.
            b={a(:).name}';                            % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            kk = {a(:).isdir}';
            kk = double([kk{:}]);
            b(kk==1) = [];
            for cntFiles = 1:length(b)
                fileName = char(b{cntFiles});
                app.TraceDropDown.Items{cntFiles} = fileName;
            end
          
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            folderPathOld = char(app.FolderEditField.Value);
            folderPath=uigetdir(folderPathOld);                        % Calls 'uigetdir' to obtain the directory location from the user   
            app.FolderEditField.Value = folderPath;      % Sets the label text to be the selected path

            a=dir(folderPath);                         % Obtains the contents of the selected path.
            b={a(:).name}';                            % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            kk = {a(:).isdir}';
            kk = double([kk{:}]);
            b(kk==1) = [];
            for cntFiles = 1:length(b)
                fileName = char(b{cntFiles});
                app.TraceDropDown.Items{cntFiles} = fileName;
            end
            %results = createPlot1(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            
            % set current data folder to be the default for this panel. 
            % Keep the other panel's default folder as it was
            if app.panelToPlot == 1
                app.CallingApp.Folder = char(app.FolderEditField.Value,app.CallingApp.Folder(2,:));
            else
                app.CallingApp.Folder = char(app.CallingApp.Folder(1,:),app.FolderEditField.Value);
            end
            
            % Call main public function for data plotting
            createPlot(app.CallingApp, app.FolderEditField.Value, app.TraceDropDown.Value);
            
            % Delete the dialog box
            delete(app)            
        end

        % Close request function: TraceSelectionUIFigure
        function TraceSelectionUIFigureCloseRequest(app, event)
            
            % Enable the Plot Opions button in main app
            if app.panelToPlot == 1
                app.CallingApp.AddTraceButton.Enable = 'on';
            else
                app.CallingApp.AddTraceButton_2.Enable = 'on';
            end
            
            % Delete the Pick File box
            delete(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
             % Enable the Plot Opions button in main app
            if app.panelToPlot == 1
                app.CallingApp.AddTraceButton.Enable = 'on';
            else
                app.CallingApp.AddTraceButton_2.Enable = 'on';
            end
            
            % Delete the Pick File box
            delete(app)           
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            folderPath = app.FolderEditField.Value;
            
            a=dir(folderPath);                         % Obtains the contents of the selected path.
            b={a(:).name}';                            % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            kk = {a(:).isdir}';
            kk = double([kk{:}]);
            b(kk==1) = [];
            for cntFiles = 1:length(b)
                fileName = char(b{cntFiles});
                app.TraceDropDown.Items{cntFiles} = fileName;
            end           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TraceSelectionUIFigure = uifigure('Visible', 'off');
            app.TraceSelectionUIFigure.Name = 'Trace Selection';
            app.TraceSelectionUIFigure.CloseRequestFcn = createCallbackFcn(app, @TraceSelectionUIFigureCloseRequest, true);
            app.FolderEditFieldLabel = uilabel(app.TraceSelectionUIFigure);
            app.FolderEditFieldLabel.Text = 'Folder:';
            app.FolderEditField = uieditfield(app.TraceSelectionUIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.TraceSelectionUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TraceDropDownLabel = uilabel(app.TraceSelectionUIFigure);
            app.TraceDropDownLabel.Text = 'Trace';
            app.TraceDropDown = uidropdown(app.TraceSelectionUIFigure);
            app.TraceDropDown.Items = {};
            app.TraceDropDown.Value = {};
            app.OKButton = uibutton(app.TraceSelectionUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.TraceSelectionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.TraceSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiPickFile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TraceSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TraceSelectionUIFigure)
        end
    end
end