classdef guiPlotTraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TraceBrowserUIFigure        matlab.ui.Figure
        Plot1Panel                  matlab.ui.container.Panel
        AddTraceButton              matlab.ui.control.Button
        RemoveTraceDropDownLabel    matlab.ui.control.Label
        RemoveTraceDropDown         matlab.ui.control.DropDown
        ButtonGroup                 matlab.ui.container.ButtonGroup
        TimeseriesButton            matlab.ui.control.RadioButton
        Button                      matlab.ui.control.RadioButton
        CompareButton               matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
        Plot2Panel                  matlab.ui.container.Panel
        AddTraceButton_2            matlab.ui.control.Button
        RemoveTraceDropDown_2Label  matlab.ui.control.Label
        RemoveTraceDropDown_2       matlab.ui.control.DropDown
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        TimeseriesButton_2          matlab.ui.control.RadioButton
        Button_2                    matlab.ui.control.RadioButton
        CompareButton_2             matlab.ui.control.RadioButton
        UIAxes2                     matlab.ui.control.UIAxes
    end

    %================================
    %
    % To do
    %
    %
    % - expose only traces that have some data in them (~allnan, ~all0)?
    %     - or plot a message instead of graph (No Data! or Trace Empty!) 
    % - are there any input parameters for the apps (to give 2 folders as inputs)?
    % - default to something when the app opens (something happens before I have to press Browse)?
    % - a properties button that shows the number of NaNs in each trace,
    %   and maybe some other useful info...
        
    properties (Access = private)
        activeTraces                % keeps all file info for both Plots: activeTraces(1).xx and activeTraces(2).xx
        traceCounts = zeros(2,1);   % the current number of traces in the plots
    end
    
    properties (Access = public)
        Folder                      % Stores current folders for both panels (****consider moving to activeTraces***)
        currentPanel = 1;           % keeping track which panel is active (selecting and plotting traces)
    end
    
    methods (Access = public)
        
        % ================================================================
        % createPlot(app,filePath,fileN)
        % 
        % Accepts the path and file name information of the next trace 
        % to be added to a plot. 
        % Stores that information and loads data
        % in app.activeTraces.
        % Updates RemoveTrace drop-down boxes.
        %
        function results = createPlot(app,filePath,fileN)
            results = 0;
            plotNum = app.currentPanel;             % establish panel to plot on
            fileName = fullfile(filePath,fileN);    % create full path to the file
            
            % Check if the trace if trace is valid (exists and it's not
            % already on the list of selected files)
            skipPlotting = false;
            if isempty(fileN)
                % skip if no trace has been added
                skipPlotting = true;                           
            elseif app.traceCounts(plotNum) >= 1
                % do not add the trace if it already exists
                for cntTrace = 1:app.traceCounts(plotNum)
                    if strcmpi(app.activeTraces(plotNum).trace(cntTrace).path,fileName)
                        % if this trace is already plotted, ignore it
                        skipPlotting = true;
                    end
                end
            end       
            
            if ~skipPlotting
                % load data
                x = read_bor(fileName);
                % load time vector (if file clean_tv exists in the same folder)
                tvPath = fullfile(filePath,'clean_tv');
                if exist(tvPath,'file')
                    tv = read_bor(tvPath,8);
                    tv = datetime(tv,'convertfrom','datenum');
                else
                    % if clean_tv does not exist, then used the index number
                    % *** add here testing for the old (vbTxt2bin) style of 
                    %     time vectors and try to load them before switching 
                    %     to the index numbers.
                    tv = 1:length(x);
                end
                % Add selected trace to the list
                cnt = app.traceCounts(plotNum) + 1;                   % increment total number of traces for this plotNum
                app.traceCounts(plotNum) = cnt;                       %
                app.activeTraces(plotNum).trace(cnt).path = fileName; % store full file name (with path)
                app.activeTraces(plotNum).trace(cnt).name = fileN;    % store just the file name
                app.activeTraces(plotNum).trace(cnt).data = x;        % store data
                app.activeTraces(plotNum).trace(cnt).tv   = tv;       % store time vector
                
                % update the dropdown box with file names
                if plotNum == 1
                    app.RemoveTraceDropDown.Items = {' '};           % clear the list
                    for cntTrace = 2:cnt+1                           % go throught the list and update RemoveTraceDropDown 
                        app.RemoveTraceDropDown.Items{cntTrace} = app.activeTraces(plotNum).trace(cntTrace-1).path; 
                    end
                else
                    app.RemoveTraceDropDown_2.Items = {' '};         % same, but for Plot2
                    for cntTrace = 2:cnt+1
                        app.RemoveTraceDropDown_2.Items{cntTrace} = app.activeTraces(plotNum).trace(cntTrace-1).path; 
                    end                    
                end
            end
            
            % Plot 1 refresh
            refreshPlot(app)                      % call to the main plotting function
    
            % Re-enable the Plot Options button            
            if plotNum == 1
                app.AddTraceButton.Enable = 'on';
            else
                app.AddTraceButton_2.Enable = 'on';
            end
            
        end        
                  
        % ================================================================
        % refreshPlot(app)
        % 
        % Updates time-series plots
        %            
        function refreshPlot(app)
            plotNum = app.currentPanel;  % panel to plot on
            if plotNum == 1
                currentAxes = app.UIAxes;
                % if updating time-series, reset the button too
                app.ButtonGroup.SelectedObject = app.ButtonGroup.Buttons(1);  
            else
                currentAxes = app.UIAxes2;
                % if updating time-series, reset the button too
                app.ButtonGroup_2.SelectedObject = app.ButtonGroup_2.Buttons(1); 
            end
            % Plot all traces
            cnt = app.traceCounts(plotNum);
            cLegend = [];
            cla(currentAxes,'reset')
            if cnt == 0
                currentAxes.Visible = false;
            else
                for cntTrace = 1:cnt
                    plot(currentAxes,app.activeTraces(plotNum).trace(cntTrace).tv,app.activeTraces(plotNum).trace(cntTrace).data,'LineWidth',2);
                    cLegend{cntTrace} = app.activeTraces(plotNum).trace(cntTrace).name; %#ok<AGROW> 
                    hold(currentAxes,"on");
                end
                hold(currentAxes,"off");
                grid(currentAxes,'on');
                zoom(currentAxes,'on')
                if cnt == 1
                    title(currentAxes,app.activeTraces(plotNum).trace(cnt).name,'Interpreter',"none")
                    legend(currentAxes,cLegend,'Interpreter',"none")
                else
                    legend(currentAxes,cLegend,'Interpreter',"none")
                end
                ylabel(currentAxes,[])
                xlabel(currentAxes,[])
                try
                    selectedButton1 = app.ButtonGroup.SelectedObject;
                    selectedButton2 = app.ButtonGroup_2.SelectedObject;
                    if ~strcmpi(selectedButton1.Text,'1:1') && ~strcmpi(selectedButton2.Text,'1:1') 
                        linkaxes([app.UIAxes app.UIAxes2],'x')
                    else
                        linkaxes([app.UIAxes app.UIAxes2],'off')
                    end
                catch
                end
                currentAxes.Visible = true;
            end
        end

        % ================================================================
        % plot1to1(app)
        % 
        % Updates 1:1 plots
        %   
        function plot1to1(app)
            plotNum = app.currentPanel;  % panel to plot on
            if plotNum == 1
                currentAxes = app.UIAxes;
            else
                currentAxes = app.UIAxes2;
            end
            % Unlink axes if one of the plots is 1:1
            linkaxes([app.UIAxes app.UIAxes2],'off')
            % get traces
            x = app.activeTraces(plotNum).trace(1).data;
            y = app.activeTraces(plotNum).trace(2).data;
            [x_filtered,y_filtered, p1_org,p2_org, slopeCoeff]= ta_clean_1to1_trace(x,y,3); %#ok<ASGLU> 
            cla(currentAxes,'reset')
            plot(currentAxes,x,y,'o',x_filtered,y_filtered,'o')
            newMin = min(y_filtered);
            newMax = max(y_filtered);
            axis(currentAxes,[newMin newMax newMin newMax])                        
            xlabel(currentAxes,app.activeTraces(plotNum).trace(1).name,'interpreter','none')
            ylabel(currentAxes,{sprintf('%s =',app.activeTraces(plotNum).trace(2).name),sprintf('y = %6.2f * x + %6.2f\n',...
                                       p2_org(1),p2_org(2))},'interpreter','none')
            grid(currentAxes,'on')
            zoom(currentAxes,'on')
            legend(currentAxes,{'Removed outliers','"Good" data'},"Location","northwest");                      
            currentAxes.Visible = true;
        end

        % ================================================================
        % plotSelectedPlotType(app,buttonGroup)
        % 
        % Selects type of plot (time-series, 1:1, compare), does some 
        % checks, calls appropriate plotting functions.
        %  
        function plotSelectedPlotType(app,buttonGroup)
            plotNum = app.currentPanel;

            selectedButton = buttonGroup.SelectedObject;
            
            if strcmpi(selectedButton.Text,'1:1')
                if app.traceCounts(plotNum) ~=2
                    uiwait(msgbox({'Select exactly 2 traces that have the same time vectors!'},"Cannot plot"))
                    buttonGroup.SelectedObject = buttonGroup.Buttons(1);
                elseif ~all(app.activeTraces(plotNum).trace(1).tv == app.activeTraces(plotNum).trace(2).tv)
                    uiwait(msgbox({'Selected traces must have same time vectors!'},"Cannot plot"))
                    buttonGroup.SelectedObject = buttonGroup.Buttons(1);                    
                else
                    plot1to1(app);
                end
            elseif strcmpi(selectedButton.Text,'Time series')
                refreshPlot(app)                
            elseif strcmpi(selectedButton.Text,'Compare')
                %selectedButton.Value = 0;
                buttonGroup.SelectedObject = buttonGroup.Buttons(1);
            end                   
        end
        
        function removeTrace(app,traceName)
            plotNum = app.currentPanel;  % panel to plot on
            for  cntTrace = 1:app.traceCounts(plotNum)
                if strcmpi(app.activeTraces(plotNum).trace(cntTrace).path,traceName)
                    
                    % remove the trace from the list
                    app.activeTraces(plotNum).trace(cntTrace) = [];
                    app.traceCounts(plotNum) = app.traceCounts(plotNum)-1;
                    
                    if plotNum == 1
                        % update the dropdown box #1 with file names
                        app.RemoveTraceDropDown.Items = {' '};
                        for cnt = 2:app.traceCounts(plotNum)+1
                            app.RemoveTraceDropDown.Items{cnt} = app.activeTraces(plotNum).trace(cnt-1).path; 
                        end
                    else
                        % update the dropdown box #2 with file names
                        app.RemoveTraceDropDown_2.Items = {' '};
                        for cnt = 2:app.traceCounts(plotNum)+1
                            app.RemoveTraceDropDown_2.Items{cnt} = app.activeTraces(plotNum).trace(cnt-1).path; 
                        end                        
                    end
                    
                    % update active plot and return
                    refreshPlot(app)                  
                    return
                end
            end   
        end
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [yearNow,~,~] = datevec(now);
            defPath = biomet_path(yearNow);
            app.Folder = char(defPath,defPath);
            colordef white
        end

        % Button pushed function: AddTraceButton
        function AddTraceButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.AddTraceButton.Enable = 'off';
            
            app.currentPanel = 1;  % panel 1 is calling guiPickFile
            
            % Open the options dialog and pass inputs
            %app.PickFileApp = guiPickFile(app);     
            guiPickFile(app);
        end

        % Value changed function: RemoveTraceDropDown
        function RemoveTraceDropDownValueChanged(app, event)
            value = app.RemoveTraceDropDown.Value;
            app.currentPanel = 1;  % panel 1 is calling removeTrace
            removeTrace(app,value)                       
        end

        % Button pushed function: AddTraceButton_2
        function AddTraceButton_2Pushed(app, event)
            % Disable Plot Options button while dialog is open
            app.AddTraceButton_2.Enable = 'off';
            
            app.currentPanel = 2;  % panel 1 is calling guiPickFile
            
            % Open the options dialog and pass inputs
            %app.PickFileApp = guiPickFile(app);     
            guiPickFile(app);      
        end

        % Value changed function: RemoveTraceDropDown_2
        function RemoveTraceDropDown_2ValueChanged(app, event)
            value = app.RemoveTraceDropDown_2.Value;
            app.currentPanel = 2;  % panel 2 is calling removeTrace            
            removeTrace(app,value)                     
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            app.currentPanel = 2;
            buttonGroup = app.ButtonGroup_2;
            plotSelectedPlotType(app,buttonGroup)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.currentPanel = 1;
            buttonGroup = app.ButtonGroup;
            plotSelectedPlotType(app,buttonGroup)       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TraceBrowserUIFigure = uifigure('Visible', 'off');
            app.TraceBrowserUIFigure.Name = 'Trace Browser';
            app.Plot1Panel = uipanel(app.TraceBrowserUIFigure);
            app.Plot1Panel.Title = 'Plot 1';
            app.AddTraceButton = uibutton(app.Plot1Panel, 'push');
            app.AddTraceButton.ButtonPushedFcn = createCallbackFcn(app, @AddTraceButtonPushed, true);
            app.AddTraceButton.Text = 'Add Trace';
            app.RemoveTraceDropDownLabel = uilabel(app.Plot1Panel);
            app.RemoveTraceDropDownLabel.Text = 'Remove Trace';
            app.RemoveTraceDropDown = uidropdown(app.Plot1Panel);
            app.RemoveTraceDropDown.Items = {};
            app.RemoveTraceDropDown.ValueChangedFcn = createCallbackFcn(app, @RemoveTraceDropDownValueChanged, true);
            app.RemoveTraceDropDown.Value = {};
            app.ButtonGroup = uibuttongroup(app.Plot1Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TimeseriesButton = uiradiobutton(app.ButtonGroup);
            app.TimeseriesButton.Text = 'Time series';
            app.TimeseriesButton.Value = true;
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '1:1';
            app.CompareButton = uiradiobutton(app.ButtonGroup);
            app.CompareButton.Text = 'Compare';
            app.UIAxes = uiaxes(app.Plot1Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.Plot2Panel = uipanel(app.TraceBrowserUIFigure);
            app.Plot2Panel.Title = 'Plot 2';
            app.AddTraceButton_2 = uibutton(app.Plot2Panel, 'push');
            app.AddTraceButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddTraceButton_2Pushed, true);
            app.AddTraceButton_2.Text = 'Add Trace';
            app.RemoveTraceDropDown_2Label = uilabel(app.Plot2Panel);
            app.RemoveTraceDropDown_2Label.Text = 'Remove Trace';
            app.RemoveTraceDropDown_2 = uidropdown(app.Plot2Panel);
            app.RemoveTraceDropDown_2.Items = {};
            app.RemoveTraceDropDown_2.ValueChangedFcn = createCallbackFcn(app, @RemoveTraceDropDown_2ValueChanged, true);
            app.RemoveTraceDropDown_2.Value = {};
            app.ButtonGroup_2 = uibuttongroup(app.Plot2Panel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.TimeseriesButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.TimeseriesButton_2.Text = 'Time series';
            app.TimeseriesButton_2.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_2.Text = '1:1';
            app.CompareButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.CompareButton_2.Text = 'Compare';
            app.UIAxes2 = uiaxes(app.Plot2Panel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.TraceBrowserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guiPlotTraces

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TraceBrowserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TraceBrowserUIFigure)
        end
    end
end