classdef plotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        HelpPushTool                   matlab.ui.container.toolbar.PushTool
        RightPanel                     matlab.ui.container.Panel
        Subpanel                       matlab.ui.container.Panel
        CompareStages                  matlab.ui.control.Button
        CompareTraces                  matlab.ui.control.Button
        SummarizeButton                matlab.ui.control.Button
        TraceSpinner                   matlab.ui.control.Spinner
        TraceSpinnerLabel              matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        LeftUpperPanel                 matlab.ui.container.Panel
        FolderEditField                matlab.ui.control.EditField
        GetDirButton_1                 matlab.ui.control.Button
        YearDropDown                   matlab.ui.control.DropDown
        SiteDropDown                   matlab.ui.control.DropDown
        DatatypeDropDown               matlab.ui.control.DropDown
        DatatypeDropDownLabel          matlab.ui.control.Label
        FolderEditFieldLabel           matlab.ui.control.Label
        LevelButtonGroup_top           matlab.ui.container.ButtonGroup
        Stage2Button_top               matlab.ui.control.RadioButton
        Stage1Button_top               matlab.ui.control.RadioButton
        RawButton_top                  matlab.ui.control.RadioButton
        TracestoppanelListBox          matlab.ui.control.ListBox
        TracestoppanelListBoxLabel     matlab.ui.control.Label
        YearDropDownLabel              matlab.ui.control.Label
        SiteDropDownLabel              matlab.ui.control.Label
        LeftLowerPanel                 matlab.ui.container.Panel
        MatchSwitch                    matlab.ui.control.Switch
        MatchSwitchLabel               matlab.ui.control.Label
        FolderEditField_2              matlab.ui.control.EditField
        GetDirButton_2                 matlab.ui.control.Button
        YearDropDown_2                 matlab.ui.control.DropDown
        SiteDropDown_2                 matlab.ui.control.DropDown
        DatatypeDropDown_2             matlab.ui.control.DropDown
        LevelButtonGroup_btm           matlab.ui.container.ButtonGroup
        Stage2Button_btm               matlab.ui.control.RadioButton
        Stage1Button_btm               matlab.ui.control.RadioButton
        RawButton_btm                  matlab.ui.control.RadioButton
        DatatypeDropDown_2Label        matlab.ui.control.Label
        SiteDropDown_2Label            matlab.ui.control.Label
        YearDropDown_2Label            matlab.ui.control.Label
        FolderEditField_2Label         matlab.ui.control.Label
        TracesbottompanelListBox       matlab.ui.control.ListBox
        TracesbottompanelListBoxLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        
        function kill_trace(app,panel)
            % Get rid of plot data if it exists
            if panel==1
                ph = app.UIAxes.Children;
                ah = app.UIAxes;
            elseif panel==2
                ph = app.UIAxes2.Children;
                ah = app.UIAxes2;
            end

            if ~isempty(ph)
                ydata = zeros(size(ph.YData));
                ph.YData = ydata;
                ylabel(ah,'')
            end
        end
        
        function [tv, input1, input2, plotFlag] = getTrace_inputs(app)
            input1 = [];
            input2 = [];
            tv = [];
            plotFlag = 0;

            selectedButton_top = app.LevelButtonGroup_top.SelectedObject;
            selectedButton_btm = app.LevelButtonGroup_btm.SelectedObject;

            % Folder for input #1
            if strcmp(selectedButton_top.Text,'Raw')
                if strcmp(app.SiteDropDown.Value,'ECCC')
                        datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                            app.YearDropDown.Value, app.SiteDropDown.Value,...
                            app.DatatypeDropDown.Value,'30min');
                else
                    datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                        app.YearDropDown.Value, app.SiteDropDown.Value,...
                        app.DatatypeDropDown.Value);
                end
            elseif strcmp(selectedButton_top.Text,'Stage1')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value,'Clean');
            elseif strcmp(selectedButton_top.Text,'Stage2')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton_top.Text,'Stage3')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton_top.Text,'Stage3+')
                datalevelFolder_1 = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            % Folder for input #2
            if strcmp(selectedButton_btm.Text,'Raw')
                if strcmp(app.SiteDropDown_2.Value,'ECCC')
                    datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value,'30min');
                else
                    datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value);
                end
            elseif strcmp(selectedButton_btm.Text,'Stage1')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value,'Clean');
            elseif strcmp(selectedButton_btm.Text,'Stage2')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton_btm.Text,'Stage3')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton_btm.Text,'Stage3+')
                datalevelFolder_2 = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            if isfolder(datalevelFolder_1) && isfolder(datalevelFolder_2)
                year1 = str2double(app.YearDropDown.Value);
                year2 = str2double(app.YearDropDown_2.Value);
                tv = read_bor(fullfile(datalevelFolder_1,'clean_tv'),8,[],year1);
                
                varname_1 = app.TracestoppanelListBox.Value;
                varname_2 = app.TracesbottompanelListBox.Value;

                if isfile(fullfile(datalevelFolder_1,varname_1)) &&...
                        isfile(fullfile(datalevelFolder_2,varname_2)) &&...
                        ~isnan(year1) && ~isnan(year2)

                    input1 = read_bor(fullfile(datalevelFolder_1,varname_1),[],[],year1);
                    input2 = read_bor(fullfile(datalevelFolder_2,varname_2),[],[],year2);
                    
                    input1(input1==-9999) = NaN;
                    input2(input2==-9999) = NaN;
                    
                    plotFlag = 1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isfile('lastFolderUsed.ini')
                fid = fopen('lastFolderUsed.ini');
                results = fscanf(fid,'%s');
                fclose(fid);
                
                app.FolderEditField.Value = results;
                FolderEditFieldValueChanged(app)
                
                app.FolderEditField_2.Value = results;
                FolderEditField_2ValueChanged(app)
            end
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            value = app.FolderEditField.Value;

            % Get rid of plot data if it exists
            kill_trace(app,1)

            if isfolder(value)
                yearFolders = dir(value);
                yearFolders = yearFolders(3:end);
                yearFolders = struct2table(yearFolders);
                yearFolders = yearFolders(yearFolders.isdir==1,:);
                yearFolders = yearFolders.name;

                % Only retain folder names that could be years
                idx_valid_years = false(length(yearFolders),1);
                for i=1:length(yearFolders)
                    tmp = str2double(yearFolders{i});
                    if ~isempty(tmp)
                        if tmp>1900 && tmp<2199
                            idx_valid_years(i,1) = true;
                        end
                    end
                end
                yearFolders = yearFolders(idx_valid_years);
                % idx = ~strcmp(yearFolders,'Calculation_Procedures');
                % yearFolders = yearFolders(idx);

                if ~isempty(yearFolders)
                    Items = cell(1,length(yearFolders)+1);
                    Items(1) = {'Choose'};
                    Items(2:end) = yearFolders';
                    app.YearDropDown.Items = Items;
                    fid = fopen('lastFolderUsed.ini','w');
                    fprintf(fid,'%s',value);
                    fclose(fid);
                else
                    Items = {'None'};
                    app.YearDropDown.Items = Items;
                end
            else
                Items = {'None'};
                app.YearDropDown.Items = Items;
            end
            app.YearDropDown.Value = app.YearDropDown.Items(1);

        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            value = app.YearDropDown.Value;
            
            app.DatatypeDropDown.Items = {'None'};
            app.DatatypeDropDown.Value = app.DatatypeDropDown.Items(1);
            app.TracestoppanelListBox.Items = {'None'};
            app.TracestoppanelListBox.Value = app.TracestoppanelListBox.Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,1)

            if strcmp(value,'Choose') || strcmp(value,'None')
                app.SiteDropDown.Items = {'None'};
            else
                yearFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value);
                if isfolder(yearFolder)
                    siteFolders = dir(yearFolder);
                    siteFolders = siteFolders(3:end);
                    siteFolders = struct2table(siteFolders);
                    n = height(siteFolders);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {siteFolders.name};
                    else
                        Items(2:end) = siteFolders.name';
                    end
                    app.SiteDropDown.Items = Items;
                else
                    app.SiteDropDown.Items = {'None'};
                end
            end
            app.SiteDropDown.Value = app.SiteDropDown.Items(1);

            if strcmp(app.MatchSwitch.Value,'On')
                app.YearDropDown_2.Items = app.YearDropDown_2.Items;
                app.YearDropDown_2.Value = app.YearDropDown.Value;

                app.SiteDropDown_2.Items = app.SiteDropDown_2.Items;
                app.SiteDropDown_2.Value = app.SiteDropDown.Value;
            end

        end

        % Value changed function: SiteDropDown
        function SiteDropDownValueChanged(app, event)
            value = app.SiteDropDown.Value;
            
            % Get rid of plot data if it exists
            kill_trace(app,1)

            if strcmp(value,'Choose') || strcmp(value,'None')
                app.DatatypeDropDown.Items = {'None'};
                app.TracestoppanelListBox.Items = {'None'};
            else
                siteFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value,value);
                if isfolder(siteFolder)
                    datatypeFolders = dir(siteFolder);
                    datatypeFolders = datatypeFolders(3:end);
                    datatypeFolders = struct2table(datatypeFolders);
                    idx = datatypeFolders.isdir==1;
                    datatypeFolders = datatypeFolders.name(idx,:);
                    n = size(datatypeFolders,1);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {datatypeFolders};
                    else
                        Items(2:end) = datatypeFolders';
                    end
                    app.DatatypeDropDown.Items = Items;
                else
                    app.DatatypeDropDown.Items = {'None'};
                end
            end
            app.DatatypeDropDown.Value = app.DatatypeDropDown.Items(1);
            app.TracestoppanelListBox.Value = app.TracestoppanelListBox.Items(1);

            if strcmp(app.MatchSwitch.Value,'On')

                app.SiteDropDown_2.Items = app.SiteDropDown_2.Items;
                app.SiteDropDown_2.Value = app.SiteDropDown.Value;
            end
        end

        % Value changed function: DatatypeDropDown
        function DatatypeDropDownValueChanged(app, event)
            value = app.DatatypeDropDown.Value;
            
            app.RawButton_top.Enable = "off";
            app.Stage1Button_top.Enable = "off";
            app.Stage2Button_top.Enable = "off";
            app.LevelButtonGroup_top.SelectedObject = app.RawButton_top;

            % Get rid of plot data if it exists
            kill_trace(app,1)

             if strcmp(value,'Choose') || strcmp(value,'None')
                app.TracestoppanelListBox.Items = {'None'};
                % app.TracesbottompanelListBox.Items = {'None'};
             else
                 datatypeFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value, value);

                 % Change radio button text dependent on data type selected
                 if strcmp(value,'Clean')
                     app.RawButton_top.Text = 'Stage2';
                     app.Stage1Button_top.Text = 'Stage3';
                     app.Stage2Button_top.Text = 'Stage3+';
                 else
                     app.RawButton_top.Text = 'Raw';
                     app.Stage1Button_top.Text = 'Stage1';
                     app.Stage2Button_top.Text = '';
                 end

                 if isfolder(datatypeFolder)
                    % Check folders before turning on other radio buttons
                    if strcmp(value,'Clean')
                        % Check for second stage folder
                        if isfolder(fullfile(datatypeFolder,'SecondStage'))
                            app.RawButton_top.Enable ="on";
                            levelFolder = fullfile(datatypeFolder,'SecondStage');
                        end
                        
                        % Check for third stage folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage'))
                            app.Stage1Button_top.Enable ="on";
                        end

                        % Check for third stage default Ustar folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage_Default_Ustar'))
                            app.Stage2Button_top.Enable ="on";
                        end
                    else
                        levelFolder = datatypeFolder;

                        % Turn on 'Raw' button by default
                        app.RawButton_top.Enable = "on";

                        % Check for Stage1 folder before activating button
                        if isfolder(fullfile(datatypeFolder,'Clean'))
                            app.Stage1Button_top.Enable ="on";
                        end
                    end

                    % traceNames = dir(datatypeFolder);
                    traceNames = dir(levelFolder);
                    traceNames = traceNames(3:end);
                    traceNames = struct2table(traceNames);
                    idx1 = ~traceNames.isdir==1;
                    idx2 = ~strcmp(traceNames.name,'clean_tv') & ~strcmp(traceNames.name,'TimeVector');
                    traceNames = traceNames.name(idx1 & idx2);
                    n = length(traceNames);
                    Items = cell(1,n+1);
                    Items(1) = {'None'};
                    if n==1
                        Items(2) = {traceNames};
                    else
                        Items(2:end) = traceNames';
                    end
                    app.TracestoppanelListBox.Items = Items;
                    % app.TracesbottompanelListBox.Items = Items;
                 else
                    app.TracestoppanelListBox.Items = {'None'};
                    % app.TracesbottompanelListBox.Items = {'None'};
                 end
             end
             % app.TracesbottompanelListBox.Value = app.TracesbottompanelListBox.Items(1);
             app.TracestoppanelListBox.Value = app.TracestoppanelListBox.Items(1);

             % % load time vector
             % year = str2double(app.YearDropDown.Value);
             % databasePth = fullfile(app.FolderEditField.Value, app.YearDropDown.Value,...
             %     app.SiteDropDown.Value, app.DatatypeDropDown.Value);
             % 
             % if isfolder(databasePth)
             %    tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
             % 
             %    % convert time vector to Matlab's datetime
             %    tv_dt = datetime(tv,'ConvertFrom','datenum');
             % 
             %    ydata = zeros(size(tv));
             % 
             %    plot(app.UIAxes, tv_dt, ydata)
             %    zoom(app.UIAxes,'on')
             %    % plot(app.UIAxes2, tv_dt, ydata)
             %    % zoom(app.UIAxes2,'on')
             %    try
             %        zoom(app.UIAxes2,'reset')
             %        % linkaxes([app.UIAxes app.UIAxes2],'x')
             %    end
             % end
        end

        % Value changed function: TracestoppanelListBox
        function TracestoppanelListBoxValueChanged(app, event)
            value = app.TracestoppanelListBox.Value;
            selectedButton = app.LevelButtonGroup_top.SelectedObject;
            
            if strcmp(selectedButton.Text,'Raw')
                databasePth = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value,app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                databasePth = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value,app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                databasePth = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value,app.SiteDropDown.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                databasePth = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value,app.SiteDropDown.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                databasePth = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value,app.SiteDropDown.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            year = str2double(app.YearDropDown.Value);
            tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
            
            % convert time vector to Matlab's datetime
            tv_dt = datetime(tv,'ConvertFrom','datenum');

            if strcmp(value,'None')
                ydata = zeros(size(tv));
            else
                ydata = read_bor(fullfile(databasePth,value),[],[],year);
                ydata(ydata==-9999) = NaN;
            end
            
            plot(app.UIAxes, tv_dt, ydata)
            ylabel(app.UIAxes, regexprep(value,'_',' '))
            
            n_missing = sum(isnan(ydata));
            title(app.UIAxes, char(['# samples missing = ' num2str(uint16(n_missing))]))
        end

        % Value changed function: TracesbottompanelListBox
        function TracesbottompanelListBoxValueChanged(app, event)
            value = app.TracesbottompanelListBox.Value;
            selectedButton = app.LevelButtonGroup_btm.SelectedObject;
            
            if strcmp(selectedButton.Text,'Raw')
                databasePth = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value,app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                databasePth = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value,app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                databasePth = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value,app.SiteDropDown_2.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                databasePth = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value,app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                databasePth = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value,app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end
            
            year = str2double(app.YearDropDown_2.Value);
            tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
            
            % convert time vector to Matlab's datetime
            tv_dt = datetime(tv,'ConvertFrom','datenum');

            if strcmp(value,'None')
                ydata = zeros(size(tv));
            else
                ydata = read_bor(fullfile(databasePth,value),[],[],year);
                ydata(ydata==-9999) = NaN;
            end
            
            plot(app.UIAxes2, tv_dt, ydata)
            ylabel(app.UIAxes2, regexprep(value,'_',' '))

            n_missing = sum(isnan(ydata));
            title(app.UIAxes2, char(['# samples missing = ' num2str(uint16(n_missing))]))
        end

        % Value changed function: FolderEditField_2
        function FolderEditField_2ValueChanged(app, event)
            value = app.FolderEditField_2.Value;
            
            app.TracesbottompanelListBox.Items = {'None'};
            app.TracesbottompanelListBox.Value = app.TracesbottompanelListBox.Items(1);
            app.RawButton_btm.Enable = "off";
            app.LevelButtonGroup_btm.SelectedObject = app.RawButton_btm;
            app.Stage1Button_btm.Enable = "off";
            app.Stage2Button_btm.Enable = "off";
            app.DatatypeDropDown_2.Items = {'None'};
            app.DatatypeDropDown_2.Value = app.DatatypeDropDown_2.Items(1);
            app.SiteDropDown_2.Items = {'None'};
            app.SiteDropDown_2.Value = app.SiteDropDown_2.Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,2)

            if isfolder(value)
                yearFolders = dir(value);
                yearFolders = yearFolders(3:end);
                yearFolders = struct2table(yearFolders);
                yearFolders = yearFolders(yearFolders.isdir==1,:);
                yearFolders = yearFolders.name;

                % Only retain folder names that could be years
                idx_valid_years = false(length(yearFolders),1);
                for i=1:length(yearFolders)
                    tmp = str2double(yearFolders{i});
                    if ~isempty(tmp)
                        if tmp>1900 && tmp<2199
                            idx_valid_years(i,1) = true;
                        end
                    end
                end
                yearFolders = yearFolders(idx_valid_years);
                % idx = ~strcmp(yearFolders,'Calculation_Procedures');
                % yearFolders = yearFolders(idx);

                if ~isempty(yearFolders)
                    Items = cell(1,length(yearFolders)+1);
                    Items(1) = {'Choose'};
                    Items(2:end) = yearFolders';
                    app.YearDropDown_2.Items = Items;
                    fid = fopen('lastFolderUsed.ini','w');
                    fprintf(fid,'%s',value);
                    fclose(fid);
                else
                    Items = {'None'};
                    app.YearDropDown_2.Items = Items;
                end
            else
                Items = {'None'};
                app.YearDropDown_2.Items = Items;
            end
            app.YearDropDown_2.Value = app.YearDropDown_2.Items(1);
        end

        % Value changed function: YearDropDown_2
        function YearDropDown_2ValueChanged(app, event)
            value = app.YearDropDown_2.Value;
            
            app.TracesbottompanelListBox.Items = {'None'};
            app.TracesbottompanelListBox.Value = app.TracesbottompanelListBox.Items(1);
            app.RawButton_btm.Enable = "off";
            app.LevelButtonGroup_btm.SelectedObject = app.RawButton_btm;
            app.Stage1Button_btm.Enable = "off";
            app.Stage2Button_btm.Enable = "off";
            app.DatatypeDropDown_2.Items = {'None'};
            app.DatatypeDropDown_2.Value = app.DatatypeDropDown_2.Items(1);

            % Get rid of plot data if it exists
            kill_trace(app,2)

            if strcmp(value,'Choose') || strcmp(value,'None')
                app.SiteDropDown_2.Items = {'None'};
            else
                yearFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value);
                if isfolder(yearFolder)
                    siteFolders = dir(yearFolder);
                    siteFolders = siteFolders(3:end);
                    siteFolders = struct2table(siteFolders);
                    n = height(siteFolders);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {siteFolders.name};
                    else
                        Items(2:end) = siteFolders.name';
                    end
                    app.SiteDropDown_2.Items = Items;
                else
                    app.SiteDropDown_2.Items = {'None'};
                end
            end
            app.SiteDropDown_2.Value = app.SiteDropDown_2.Items(1);
        end

        % Value changed function: SiteDropDown_2
        function SiteDropDown_2ValueChanged(app, event)
            value = app.SiteDropDown_2.Value;
            
            app.TracesbottompanelListBox.Items = {'None'};
            app.TracesbottompanelListBox.Value = app.TracesbottompanelListBox.Items(1);
            app.RawButton_btm.Enable = "off";
            app.LevelButtonGroup_btm.SelectedObject = app.RawButton_btm;
            app.Stage1Button_btm.Enable = "off";
            app.Stage2Button_btm.Enable = "off";
            
            % Get rid of plot data if it exists
            kill_trace(app,2)

            if strcmp(value,'Choose') || strcmp(value,'None')
                app.DatatypeDropDown_2.Items = {'None'};
            else
                siteFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value,value);
                if isfolder(siteFolder)
                    datatypeFolders = dir(siteFolder);
                    datatypeFolders = datatypeFolders(3:end);
                    datatypeFolders = struct2table(datatypeFolders);
                    idx = datatypeFolders.isdir==1;
                    datatypeFolders = datatypeFolders.name(idx,:);
                    n = size(datatypeFolders,1);
                    Items = cell(1,n+1);
                    Items(1) = {'Choose'};
                    if n==1
                        Items(2) = {datatypeFolders};
                    else
                        Items(2:end) = datatypeFolders';
                    end
                    app.DatatypeDropDown_2.Items = Items;
                else
                    app.DatatypeDropDown_2.Items = {'None'};
                end
            end
            app.DatatypeDropDown_2.Value = app.DatatypeDropDown_2.Items(1);
        end

        % Value changed function: DatatypeDropDown_2
        function DatatypeDropDown_2ValueChanged(app, event)
            % Get new value from Datatype drop down list
            value = app.DatatypeDropDown_2.Value;
            
            app.RawButton_btm.Enable = "off";
            app.Stage1Button_btm.Enable = "off";
            app.Stage2Button_btm.Enable = "off";
            app.LevelButtonGroup_btm.SelectedObject = app.RawButton_btm;

            % Get rid of plot data if it exists
            kill_trace(app,2)
            
            if strcmp(value,'Choose') || strcmp(value,'None')
                % Turn off radio buttons and clear trace list
                app.TracesbottompanelListBox.Items = {'None'};
            else
                % Selected data type folder
                 datatypeFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value, value);
                
                 % Change radio button text dependent on data type selected
                 if strcmp(value,'Clean')
                     app.RawButton_btm.Text = 'Stage2';
                     app.Stage1Button_btm.Text = 'Stage3';
                     app.Stage2Button_btm.Text = 'Stage3+';
                 else
                     app.RawButton_btm.Text = 'Raw';
                     app.Stage1Button_btm.Text = 'Stage1';
                     app.Stage2Button_btm.Text = '';
                 end

                 if isfolder(datatypeFolder)
                    % Check folders before turning on other radio buttons
                    if strcmp(value,'Clean')
                        % Check for second stage folder
                        if isfolder(fullfile(datatypeFolder,'SecondStage'))
                            app.RawButton_btm.Enable ="on";
                            levelFolder = fullfile(datatypeFolder,'SecondStage');
                        end
                        
                        % Check for third stage folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage'))
                            app.Stage1Button_btm.Enable ="on";
                        end

                        % Check for third stage default Ustar folder
                        if isfolder(fullfile(datatypeFolder,'ThirdStage_Default_Ustar'))
                            app.Stage2Button_btm.Enable ="on";
                        end
                    else
                        levelFolder = datatypeFolder;

                        % Turn on 'Raw' button by default
                        app.RawButton_btm.Enable = "on";

                        % Check for Stage1 folder before activating button
                        if isfolder(fullfile(datatypeFolder,'Clean'))
                            app.Stage1Button_btm.Enable ="on";
                        end
                    end
                    
                    traceNames = dir(levelFolder);
                    traceNames = traceNames(3:end);
                    traceNames = struct2table(traceNames);
                    idx1 = ~traceNames.isdir==1;
                    idx2 = ~strcmp(traceNames.name,'clean_tv') & ~strcmp(traceNames.name,'TimeVector');
                    traceNames = traceNames.name(idx1 & idx2);
                    n = length(traceNames);
                    Items = cell(1,n+1);
                    Items(1) = {'None'};
                    if n==1
                        Items(2) = {traceNames};
                    else
                        Items(2:end) = traceNames';
                    end
                    % app.TracestoppanelListBox.Items = Items;
                    app.TracesbottompanelListBox.Items = Items;
                 else
                    % app.TracestoppanelListBox.Items = {'None'};
                    app.TracesbottompanelListBox.Items = {'None'};
                 end
             end
             app.TracesbottompanelListBox.Value = app.TracesbottompanelListBox.Items(1);

             % % load time vector
             % year = str2double(app.YearDropDown_2.Value);
             % databasePth = fullfile(app.FolderEditField_2.Value, app.YearDropDown_2.Value,...
             %     app.SiteDropDown_2.Value, app.DatatypeDropDown_2.Value);
             % 
             % if isfolder(databasePth)
             %    tv = read_bor(fullfile(databasePth,'clean_tv'),8,[],year);
             % 
             %    % convert time vector to Matlab's datetime
             %    tv_dt = datetime(tv,'ConvertFrom','datenum');
             % 
             %    ydata = zeros(size(tv));
             % 
             %    plot(app.UIAxes2, tv_dt, ydata)
             %    zoom(app.UIAxes2,'on')
             %    try
             %        zoom(app.UIAxes,'out')
             %        % linkaxes([app.UIAxes app.UIAxes2],'x')
             %    end
             % end
        end

        % Selection changed function: LevelButtonGroup_btm
        function LevelButtonGroup_btmSelectionChanged(app, event)
            selectedButton = app.LevelButtonGroup_btm.SelectedObject;
            
            if strcmp(selectedButton.Text,'Raw')
                datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    app.DatatypeDropDown_2.Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                    app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            traceNames = dir(datalevelFolder);
            traceNames = traceNames(3:end);
            traceNames = struct2table(traceNames);
            idx1 = ~traceNames.isdir==1;
            idx2 = ~strcmp(traceNames.name,'clean_tv') & ~strcmp(traceNames.name,'TimeVector');
            traceNames = traceNames.name(idx1 & idx2);
            n = length(traceNames);
            Items = cell(1,n+1);
            Items(1) = {'None'};
            if n==1
                Items(2) = {traceNames};
            else
                Items(2:end) = traceNames';
            end
            % app.TracestoppanelListBox.Items = Items;
            app.TracesbottompanelListBox.Items = Items;
            app.TracesbottompanelListBox.Value = app.TracesbottompanelListBox.Items(1);

            if isfolder(datalevelFolder)
                year = str2double(app.YearDropDown_2.Value);
                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
            
                % convert time vector to Matlab's datetime
                tv_dt = datetime(tv,'ConvertFrom','datenum');

                ydata = zeros(size(tv));

                plot(app.UIAxes2, tv_dt, ydata)
                zoom(app.UIAxes2,'on')
                try
                    zoom(app.UIAxes,'out')
                    % linkaxes([app.UIAxes app.UIAxes2],'x')
                end
             end
        end

        % Button pushed function: CompareTraces
        function CompareTracesPushed(app, event)

            [tv, input1, input2, plotFlag] = getTrace_inputs(app);
            
            % Only compare traces from the same year
            input1_year = app.YearDropDown.Value;
            input2_year = app.YearDropDown_2.Value;

            years = (1900:2099)';
            years_str = num2str(years);
            valid_years = cellstr(years_str);

            if sum(strcmp(valid_years,input1_year))==1 &&...
                    sum(strcmp(valid_years,input2_year))==1
                if ~strcmp(input1_year,input2_year)
                    msgStr = 'Years must be the same.';
                    plotFlag = 0;
                end

                if strcmp(app.TracestoppanelListBox.Value,'None') ||...
                        strcmp(app.TracesbottompanelListBox.Value,'None')
                    msgStr = 'Choose valid traces.';
                    plotFlag = 0;
                end
            else
                msgStr = 'Choose a valid year.';
                plotFlag = 0;
            end

            if plotFlag==1
                simple_trace_comparison(tv, input1, input2)
            else
                msgStruct.Interpreter = 'tex';
                msgStruct.WindowStyle = 'non-modal';
                errordlg(char(['\fontsize{12} ' msgStr]),'',msgStruct)
            end
        end

        % Button pushed function: SummarizeButton
        function SummarizeButtonPushed(app, event)
            plotFlag = 0;
            selectedButton = app.LevelButtonGroup_btm.SelectedObject;
            traceValue = app.TraceSpinner.Value;
            
            if traceValue==1
                % Folder for input #1
                % if strcmp(selectedButton.Text,'Raw')
                    datalevelFolder = fullfile(app.FolderEditField.Value,...
                        app.YearDropDown.Value, app.SiteDropDown.Value,...
                        app.DatatypeDropDown.Value);
                % elseif strcmp(selectedButton.Text,'Stage1')
                %     datalevelFolder_1 = fullfile(app.FolderEditField_2.Value,...
                %         app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                %         app.DatatypeDropDown_2.Value,'Clean');
                % end
            elseif traceValue==2
                % Folder for input #2
                if strcmp(selectedButton.Text,'Raw')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value);
                elseif strcmp(selectedButton.Text,'Stage1')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        app.DatatypeDropDown_2.Value,'Clean');
                elseif strcmp(selectedButton.Text,'Stage2')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','SecondStage');
                elseif strcmp(selectedButton.Text,'Stage3')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','ThirdStage');
                elseif strcmp(selectedButton.Text,'Stage3+')
                    datalevelFolder = fullfile(app.FolderEditField_2.Value,...
                        app.YearDropDown_2.Value, app.SiteDropDown_2.Value,...
                        'Clean','ThirdStage_Default_Ustar');
                end
            end

            if isfolder(datalevelFolder)
                if traceValue==1
                    year = str2double(app.YearDropDown.Value);
                    varname = app.TracestoppanelListBox.Value;
                elseif traceValue==2
                    year = str2double(app.YearDropDown_2.Value);
                    varname = app.TracesbottompanelListBox.Value;
                end

                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
                
                if isfile(fullfile(datalevelFolder,varname)) && ~isnan(year)
                    input1 = read_bor(fullfile(datalevelFolder,varname),[],[],year);
                    input1(input1==-9999) = NaN;
                    plotFlag = 1;
                end
            end

            if plotFlag==1
                simple_trace_summary(tv, input1)
            else
                msgStruct.Interpreter = 'tex';
                msgStruct.WindowStyle = 'non-modal';
                errordlg('\fontsize{12} No trace to summarize.','',msgStruct)
            end
        end

        % Size changed function: Subpanel
        function SubpanelSizeChanged(app, event)
            position = app.Subpanel.Position;
            
        end

        % Button pushed function: GetDirButton_1
        function GetDirButton_1Pushed(app, event)
            fldr = uigetdir([],'Select database folder');

            app.FolderEditField.Value = fldr;
            FolderEditFieldValueChanged(app)
        end

        % Button pushed function: GetDirButton_2
        function GetDirButton_2Pushed(app, event)
            fldr = uigetdir([],'Select database folder');

            app.FolderEditField_2.Value = fldr;
            FolderEditField_2ValueChanged(app)
        end

        % Selection changed function: LevelButtonGroup_top
        function LevelButtonGroup_topSelectionChanged(app, event)
            selectedButton = app.LevelButtonGroup_top.SelectedObject;
            
            if strcmp(selectedButton.Text,'Raw')
                datalevelFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value);
            elseif strcmp(selectedButton.Text,'Stage1')
                datalevelFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    app.DatatypeDropDown.Value,'Clean');
            elseif strcmp(selectedButton.Text,'Stage2')
                datalevelFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','SecondStage');
            elseif strcmp(selectedButton.Text,'Stage3')
                datalevelFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage');
            elseif strcmp(selectedButton.Text,'Stage3+')
                datalevelFolder = fullfile(app.FolderEditField.Value,...
                    app.YearDropDown.Value, app.SiteDropDown.Value,...
                    'Clean','ThirdStage_Default_Ustar');
            end

            traceNames = dir(datalevelFolder);
            traceNames = traceNames(3:end);
            traceNames = struct2table(traceNames);
            idx1 = ~traceNames.isdir==1;
            idx2 = ~strcmp(traceNames.name,'clean_tv') & ~strcmp(traceNames.name,'TimeVector');
            traceNames = traceNames.name(idx1 & idx2);
            n = length(traceNames);
            Items = cell(1,n+1);
            Items(1) = {'None'};
            if n==1
                Items(2) = {traceNames};
            else
                Items(2:end) = traceNames';
            end
            % app.TracestoppanelListBox.Items = Items;
            app.TracestoppanelListBox.Items = Items;
            app.TracestoppanelListBox.Value = app.TracestoppanelListBox.Items(1);

            if isfolder(datalevelFolder)
                year = str2double(app.YearDropDown.Value);
                tv = read_bor(fullfile(datalevelFolder,'clean_tv'),8,[],year);
            
                % convert time vector to Matlab's datetime
                tv_dt = datetime(tv,'ConvertFrom','datenum');

                ydata = zeros(size(tv));

                plot(app.UIAxes, tv_dt, ydata)
                zoom(app.UIAxes,'on')
                try
                    zoom(app.UIAxes2,'out')
                    % linkaxes([app.UIAxes app.UIAxes2],'x')
                end
             end
        end

        % Button pushed function: CompareStages
        function CompareStagesPushed(app, event)

            [tv, input1, input2, plotFlag] = getTrace_inputs(app);

            yearX = datevec(tv(1));
            siteID = app.SiteDropDown.Value;
            disp(siteID)
            if plotFlag==1 & ~isempty(input1) & ~isempty(input2)
                fr_compare_database_traces2(siteID,yearX,[],[],[],...
                    [],[],input1,input2)
            end
        end

        % Callback function: HelpPushTool
        function HelpPushToolClicked(app, event)
            message = {'This app was initiallly designed to quickly view traces in the database and select potential alternate traces to use for gap filling.',...
                '',...
                'Type in a siteID\Database\ folder name or use the browse button to select the directory to search for a trace.',...
                '',...
                'The [Summarize] button generates several summary plots for a selected trace in a new window. The trace that is summrized is based on the value in the [Trace] field (i.e. 1-top; 2-bottom).',...
                '',...
                'The [Compare] button generates several comparison plots for the two selected traces in a new window. It only allows comparisons of traces from the same year (if working properly). Different sites, data types, and levels can be compared.',...
                '',...
                'For both [Compare] and [Summarize], only the upper left panel in the new window will be interactive (i.e. zoom/pan). The data in the other sub-panels will automatically update based on what is visible in the main window.',...
                '',...
                'The shaded out button will allow you to examine changes in a trace across stages using Zoran`s fr_compare_database_traces(). Since trace names might differ between the raw database and subsequent stages, robust error checking is still in progress...',...
                '',...
                '',...
                'In the future, the help button will ideally open documentation on GitHub.',...
                ''};

            msgbox(message,'Overview','modal');
        end

        % Value changed function: MatchSwitch
        function MatchSwitchValueChanged(app, event)
            value = app.MatchSwitch.Value;
            
            if strcmp(value,'On')
                app.FolderEditField.Enable = 'off';
                app.YearDropDown_2.Enable = 'off';
                app.SiteDropDown_2.Enable = 'off';

                app.YearDropDown_2.Value = app.YearDropDown.Value;
                app.SiteDropDown_2.Value = app.SiteDropDown.Value;
                app.FolderEditField_2.Value = app.FolderEditField.Value;
            elseif strcmp(value,'Off')
                app.FolderEditField_2.Enable = 'on';
                app.YearDropDown_2.Enable = 'on';
                app.SiteDropDown_2.Enable = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.ClickedCallback = createCallbackFcn(app, @HelpPushToolClicked, true);
            app.HelpPushTool.Icon = 'Help_24.png';
            app.LeftLowerPanel = uipanel(app.UIFigure);
            app.TracesbottompanelListBoxLabel = uilabel(app.LeftLowerPanel);
            app.TracesbottompanelListBoxLabel.Text = 'Traces (bottom  panel)';
            app.TracesbottompanelListBox = uilistbox(app.LeftLowerPanel);
            app.TracesbottompanelListBox.Items = {'None'};
            app.TracesbottompanelListBox.ValueChangedFcn = createCallbackFcn(app, @TracesbottompanelListBoxValueChanged, true);
            app.TracesbottompanelListBox.Value = 'None';
            app.FolderEditField_2Label = uilabel(app.LeftLowerPanel);
            app.FolderEditField_2Label.Text = 'Folder';
            app.YearDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.YearDropDown_2Label.Text = 'Year';
            app.SiteDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.SiteDropDown_2Label.Text = 'Site';
            app.DatatypeDropDown_2Label = uilabel(app.LeftLowerPanel);
            app.DatatypeDropDown_2Label.Text = 'Data type';
            app.LevelButtonGroup_btm = uibuttongroup(app.LeftLowerPanel);
            app.LevelButtonGroup_btm.SelectionChangedFcn = createCallbackFcn(app, @LevelButtonGroup_btmSelectionChanged, true);
            app.LevelButtonGroup_btm.Title = 'Level';
            app.RawButton_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.RawButton_btm.Enable = 'off';
            app.RawButton_btm.Text = 'Raw';
            app.RawButton_btm.Value = true;
            app.Stage1Button_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.Stage1Button_btm.Enable = 'off';
            app.Stage1Button_btm.Text = 'Stage1';
            app.Stage2Button_btm = uiradiobutton(app.LevelButtonGroup_btm);
            app.Stage2Button_btm.Enable = 'off';
            app.Stage2Button_btm.Text = 'Stage2';
            app.DatatypeDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.DatatypeDropDown_2.Items = {'None'};
            app.DatatypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDown_2ValueChanged, true);
            app.DatatypeDropDown_2.Value = 'None';
            app.SiteDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.SiteDropDown_2.Items = {'None'};
            app.SiteDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SiteDropDown_2ValueChanged, true);
            app.SiteDropDown_2.Value = 'None';
            app.YearDropDown_2 = uidropdown(app.LeftLowerPanel);
            app.YearDropDown_2.Items = {'2999'};
            app.YearDropDown_2.ValueChangedFcn = createCallbackFcn(app, @YearDropDown_2ValueChanged, true);
            app.YearDropDown_2.Value = '2999';
            app.GetDirButton_2 = uibutton(app.LeftLowerPanel, 'push');
            app.GetDirButton_2.ButtonPushedFcn = createCallbackFcn(app, @GetDirButton_2Pushed, true);
            app.GetDirButton_2.Icon = 'Browse_16.png';
            app.GetDirButton_2.Tooltip = {'Select database folder'};
            app.GetDirButton_2.Text = '';
            app.FolderEditField_2 = uieditfield(app.LeftLowerPanel, 'text');
            app.FolderEditField_2.ValueChangedFcn = createCallbackFcn(app, @FolderEditField_2ValueChanged, true);
            app.FolderEditField_2.Tooltip = {'Type in database folder path.'};
            app.MatchSwitchLabel = uilabel(app.LeftLowerPanel);
            app.MatchSwitchLabel.Enable = 'off';
            app.MatchSwitchLabel.Tooltip = {'When ''On'', [Folder], [Year] and [Site] forced to match top '};
            app.MatchSwitchLabel.Text = 'Match';
            app.MatchSwitch = uiswitch(app.LeftLowerPanel, 'slider');
            app.MatchSwitch.ValueChangedFcn = createCallbackFcn(app, @MatchSwitchValueChanged, true);
            app.MatchSwitch.Enable = 'off';
            app.MatchSwitch.Tooltip = {'When ''On'', [Folder], [Year] and [Site] forced to match top panel.'};
            app.LeftUpperPanel = uipanel(app.UIFigure);
            app.SiteDropDownLabel = uilabel(app.LeftUpperPanel);
            app.SiteDropDownLabel.Text = 'Site';
            app.YearDropDownLabel = uilabel(app.LeftUpperPanel);
            app.YearDropDownLabel.Text = 'Year';
            app.TracestoppanelListBoxLabel = uilabel(app.LeftUpperPanel);
            app.TracestoppanelListBoxLabel.Text = 'Traces (top panel)';
            app.TracestoppanelListBox = uilistbox(app.LeftUpperPanel);
            app.TracestoppanelListBox.Items = {'None'};
            app.TracestoppanelListBox.ValueChangedFcn = createCallbackFcn(app, @TracestoppanelListBoxValueChanged, true);
            app.TracestoppanelListBox.Value = 'None';
            app.LevelButtonGroup_top = uibuttongroup(app.LeftUpperPanel);
            app.LevelButtonGroup_top.SelectionChangedFcn = createCallbackFcn(app, @LevelButtonGroup_topSelectionChanged, true);
            app.LevelButtonGroup_top.Title = 'Level';
            app.RawButton_top = uiradiobutton(app.LevelButtonGroup_top);
            app.RawButton_top.Enable = 'off';
            app.RawButton_top.Text = 'Raw';
            app.RawButton_top.Value = true;
            app.Stage1Button_top = uiradiobutton(app.LevelButtonGroup_top);
            app.Stage1Button_top.Enable = 'off';
            app.Stage1Button_top.Text = 'Stage1';
            app.Stage2Button_top = uiradiobutton(app.LevelButtonGroup_top);
            app.Stage2Button_top.Enable = 'off';
            app.Stage2Button_top.Text = 'Stage2';
            app.FolderEditFieldLabel = uilabel(app.LeftUpperPanel);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.DatatypeDropDownLabel = uilabel(app.LeftUpperPanel);
            app.DatatypeDropDownLabel.Text = 'Data type';
            app.DatatypeDropDown = uidropdown(app.LeftUpperPanel);
            app.DatatypeDropDown.Items = {'None'};
            app.DatatypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatypeDropDownValueChanged, true);
            app.DatatypeDropDown.Value = 'None';
            app.SiteDropDown = uidropdown(app.LeftUpperPanel);
            app.SiteDropDown.Items = {'None'};
            app.SiteDropDown.ValueChangedFcn = createCallbackFcn(app, @SiteDropDownValueChanged, true);
            app.SiteDropDown.Value = 'None';
            app.YearDropDown = uidropdown(app.LeftUpperPanel);
            app.YearDropDown.Items = {'2999'};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2999';
            app.GetDirButton_1 = uibutton(app.LeftUpperPanel, 'push');
            app.GetDirButton_1.ButtonPushedFcn = createCallbackFcn(app, @GetDirButton_1Pushed, true);
            app.GetDirButton_1.Icon = 'Browse_16.png';
            app.GetDirButton_1.Tooltip = {'Select database folder.'};
            app.GetDirButton_1.Text = '';
            app.FolderEditField = uieditfield(app.LeftUpperPanel, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.FolderEditField.Tooltip = {'Type in database folder path.'};
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Subpanel = uipanel(app.RightPanel);
            app.Subpanel.SizeChangedFcn = createCallbackFcn(app, @SubpanelSizeChanged, true);
            app.TraceSpinnerLabel = uilabel(app.Subpanel);
            app.TraceSpinnerLabel.Text = 'Trace';
            app.TraceSpinner = uispinner(app.Subpanel);
            app.TraceSpinner.Limits = [1 2];
            app.TraceSpinner.Tooltip = {'Plot to [Summarize]: 1 - top; 2- bottom'};
            app.TraceSpinner.Value = 1;
            app.SummarizeButton = uibutton(app.Subpanel, 'push');
            app.SummarizeButton.ButtonPushedFcn = createCallbackFcn(app, @SummarizeButtonPushed, true);
            app.SummarizeButton.Tooltip = {'Basic summary plots for selected trace.'};
            app.SummarizeButton.Text = 'Summarize';
            app.CompareTraces = uibutton(app.Subpanel, 'push');
            app.CompareTraces.ButtonPushedFcn = createCallbackFcn(app, @CompareTracesPushed, true);
            app.CompareTraces.Tooltip = {'Basic comparison plots between traces.'};
            app.CompareTraces.Text = 'Compare';
            app.CompareStages = uibutton(app.Subpanel, 'push');
            app.CompareStages.ButtonPushedFcn = createCallbackFcn(app, @CompareStagesPushed, true);
            app.CompareStages.Icon = 'Compare_16.png';
            app.CompareStages.Enable = 'off';
            app.CompareStages.Tooltip = {'Will run fr_compare_database_traces()'};
            app.CompareStages.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end