classdef setupLocalDataCleaning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetupDataCleaningUIFigure     matlab.ui.Figure
        DatabaseserverlocationEditFieldLabel  matlab.ui.control.Label
        DatabaseserverlocationEditField  matlab.ui.control.EditField
        BrowseDatabaseFolder          matlab.ui.control.Button
        CopyDatabaseButton            matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        BrowseLocalDatabaseFolder     matlab.ui.control.Button
        LocalcopyofdatabaseEditFieldLabel  matlab.ui.control.Label
        LocalcopyofdatabaseEditField  matlab.ui.control.EditField
        SiteSelectionDropDownLabel    matlab.ui.control.Label
        SiteSelectionDropDown         matlab.ui.control.DropDown
        YearsEditFieldLabel           matlab.ui.control.Label
        YearsEditField                matlab.ui.control.EditField
        SetupDataCleaningonLocalComputerLabel  matlab.ui.control.Label
        BrowseUserFolder              matlab.ui.control.Button
        UserworkfolderLabel           matlab.ui.control.Label
        UserworkfolderEditField       matlab.ui.control.EditField
    end

    % setupLocalDataCleaning.mlapp
    %
    % This app enables users to do local cleaning of database traces
    % by copying the server database to the local PC. User can then work 
    % on the ini files, do the cleaning and check the results. Once everything
    % is working, the user can (manually) copy the ini files and any functions 
    % from Derived_Variables to the main server and re-run the cleaning there to
    % update the server database.
    % The results should be the same as after the cleaning on the local PC.
    % 
    % Steps for local cleaning (including editing of the ini files):
    %   - Create a folder (or use an existing one if you have the folder already exists) 
    %     for the data cleaning that you are about to do 
    %     (usually there would be one folder per each site on your
    %     local computer). Suggested name: (./user_name/Matlab/local_data_cleaning/site_name)
    %     The biomet_database_default.m and localDataCleaning_ini.mat
    %     files are stored in that folder. They will be created when the app runs the first time.
    %   - In Matlab, cd to that folder
    %   - run setupLocalDataCleaning from the command line
    %   - change the app settings if needed. The program always saves the settings
    %     after each successful run. Those settings become the defaults for the next
    %     app run. If the user starts the app while being in the same folder,
    %     the setup info saved during the previous run (file localDataCleaning_ini.mat) will
    %     be loaded with the default settings for the GUI.
    %   - click on "Copy Database" and wait for the copy to finish. The program may stop and warn
    %     you if you are about to overwrite some newer files in your local database. Read the prompts!
    %   - Afte the app closes, you'll have your own copy of the database and, 
    %     as long as you stay in this folder, 
    %     all the cleaning will be done on your local copy of the database (because biomet_database_default.m
    %     points to your local database).
    %  Ini files
    %   - copy the newest version of the ini files from ../Database/Calculation_Procedures/TraceAnalysis_ini/DSM
    %     into your local folder - the same one from which you ran setupLocalDataCleaning app
    %     (./user_name/Matlab/local_data_cleaning/site_name)
    %   - edit and save ini files
    %  fr_automated_cleaning()
    %   - run:
    %         fr_automated_cleaning(2021:2022,'DSM',[],'./user_name/Matlab/local_data_cleaning/site_name')
    %
    %
    %  Zoran Nesic                  File created:           Sep 19, 2022
    %                               Last modification:      Apr 14, 2023
    %
    % 
    % Revisions
    %
    % May 23, 2023 (Zoran)
    %   - The app worked only for Micromet sites. Fixed so that it works
    %     with any database. There are two options:
    %       - user directory contains a matlab file
    %         biomet_database_default.m with one line:
    %         pth ='your_pointer_to_database_source'
    %       - the computer has Biomet.net installed in which case
    %         the path will be set using biomet_path function
    %       - if both options fail, the computer assumes the path is
    %         /vinimet.geog.ubc.ca/Projects/Database
    % Apr 14, 2023 (Zoran)
    %   - removed feature: copying of Calculation_Procedures folders is not anymore done
    %     through this app. The way to keep all the ini files up to date is to use GitHub
    %
    
    properties (Access = private)
        copyCMD                 % string containing the copy command 
        defFontSize = 12;       % Font size for dialog boxes
    end
    
    methods (Access = private)
        
        function safe_copyfile(app,sourcePath,destinationPath)
            % This function's logic:
            %    - Copies sourcePath file if it doesn't exist in the destination folder
            %    - Doesn't do anything if the source and destination are the same
            %      (both: date and size)
            %    - Asks for confirmation if the destination file is newer than the
            %      source file
            %    - Copies over if the destination is older
            %
            % NOTE: It copies only a single file!
            %
            
            destinationInfo = dir(destinationPath);
            sourceInfo      = dir(sourcePath);
            if length(destinationInfo)> 1 || length(sourceInfo)> 1
                error('Inputs in safe_copy() have to be individual files.')
            end
            %
            if isempty(sourceInfo)
                % The source file does not exist. Pop up a warning
                h = warndlg(sprintf('File: %s does not exist. Skipping...',sourcePath));
                msgboxFontSize(h,app.defFontSize);
                uiwait(h,5)
                delete(h)
            elseif isempty(destinationInfo) || destinationInfo.datenum < sourceInfo.datenum
                % if the destination file does not exist or it's older copy the file
                copyfile(sourcePath,destinationPath)
            elseif destinationInfo.datenum == sourceInfo.datenum && destinationInfo.bytes == sourceInfo.bytes
                % do nothing if the files are the same
            else
                % Ask for confirmation 
                %buttonName = uiconfirm(app.SetupDataCleaningUIFigure,'The destination file exists and it is newer than the source. Do you want to overwrite it?',"Confirm overwrite",{'Yes','No'},'defaultoption',2)
                spltSourcePath = split(sourcePath,filesep);
                fileNameStr = deblank(char(spltSourcePath(end,:)));
                fileNameStr = regexprep(fileNameStr,'_','\\_');
                msgTxt = sprintf('\\fontsize{12}The destination file (%s) exists and it is newer than the source. Do you want to overwrite it?',fileNameStr);
                opts = struct('WindowStyle','replace','Default','No','Interpreter','tex');
                buttonName = questdlg(msgTxt,"Confirm overwrite","Yes","No",opts);
                switch buttonName
                    case 'Yes'
                        try
                            copyfile(sourcePath,destinationPath)
                        catch
                            error('Failed copying %s to %s.\n',sourcePath,destinationPath);
                        end
                    otherwise
                end
            end           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if exist('localDataCleaning_ini.mat','file')
                % Check if there is an ini file and use it for 
                % setting up initial values
                load('localDataCleaning_ini.mat','inputInfo'); 
                app.YearsEditField.Value = strtrim(inputInfo.Years);  
                app.DatabaseserverlocationEditField.Value = inputInfo.databaseServerPath;
                    all_folders=dir(fullfile(app.DatabaseserverlocationEditField.Value,app.YearsEditField.Value(end-3:end)));
                    all_folders(contains({all_folders.name},{'.','..'}))=[];                
                app.SiteSelectionDropDown.Items={all_folders(vertcat(all_folders(:).isdir)==1).name};
                app.SiteSelectionDropDown.Value = inputInfo.siteID;                
                app.LocalcopyofdatabaseEditField.Value = inputInfo.databaseLocalPath;
                app.UserworkfolderEditField.Value = inputInfo.workFolderPath;          
            else
                % If there is no ini file, use these start values
                app.YearsEditField.Value = num2str(year(datetime('now')));
                if exist('biomet_database_default','file')
                    app.DatabaseserverlocationEditField.Value = biomet_database_default;
                elseif exist('db_pth_root','file')
                    app.DatabaseserverlocationEditField.Value = db_pth_root;
                else                                
                    if ispc
                        app.DatabaseserverlocationEditField.Value = '//vinimet.geog.ubc.ca/Projects/Database';
                    else
                        % Sara and I need to fix this up
                        % Option1
                        %    - preset location for all users, Sara decides where
                        % Option2
                        %    - App mounts the drive automatically and knows where it is
                        %    - see: https://apple.stackexchange.com/questions/697/how-can-i-mount-an-smb-share-from-the-command-line
                        %
                        app.DatabaseserverlocationEditField.Value = '/Volumes/Database';
                    end    
                end
                all_folders=dir(fullfile(app.DatabaseserverlocationEditField.Value,app.YearsEditField.Value));
                    all_folders(contains({all_folders.name},{'.','..'}))=[];                
                app.SiteSelectionDropDown.Items={all_folders(vertcat(all_folders(:).isdir)==1).name};      
                app.SiteSelectionDropDown.Value = char(app.SiteSelectionDropDown.Items{1});
                app.UserworkfolderEditField.Value = pwd;        
                app.LocalcopyofdatabaseEditField.Value = fullfile(tempdir,'local_database');                
                
            end
            if ispc
                app.copyCMD = 'robocopy %s %s /R:3 /W:5 /REG /MIR /NDL /NFL /NJH';
            else
                app.copyCMD = 'rsync -av --update %s %s';
            end

        end

        % Button pushed function: BrowseUserFolder
        function BrowseUserFolderPushed(app, event)
            folderPathOld = char(app.UserworkfolderEditField.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);    %create a dummy figure so that uigetfile doesn't minimize our GUI
            folderPath=uigetdir(folderPathOld);                                 % Calls 'uigetdir' to obtain the directory location from the user 
            delete(f);                                                          % remove dummy figure
            if folderPath ~= 0                                                  % when uigetdir gets canceled without making a selection it returns double(0)
                app.UserworkfolderEditField.Value = folderPath;                 % Sets the label text to be the selected path
            end
        end

        % Button pushed function: BrowseDatabaseFolder
        function BrowseDatabaseFolderButtonPushed(app, event)
            folderPathOld = char(app.DatabaseserverlocationEditField.Value);
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);    %create a dummy figure so that uigetfile doesn't minimize our GUI
            folderPath=uigetdir(folderPathOld);                                 % Calls 'uigetdir' to obtain the directory location from the user  
            delete(f);
            if folderPath ~= 0
                app.DatabaseserverlocationEditField.Value = folderPath;         % Sets the label text to be the selected path       
            end
        end

        % Button pushed function: BrowseLocalDatabaseFolder
        function BrowseLocalDatabaseFolderButtonPushed(app, event)
            folderPathOld = char(app.LocalcopyofdatabaseEditField.Value);
            folderPath=uigetdir(folderPathOld);                                 % Calls 'uigetdir' to obtain the directory location from the user
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);    %create a dummy figure so that uigetfile doesn't minimize our GUI
            delete(f);
            if folderPath ~= 0
                app.LocalcopyofdatabaseEditField.Value = folderPath;            % Sets the label text to be the selected path      
            end
        end

        % Button pushed function: CopyDatabaseButton
        function CopyDatabaseButtonPushed(app, event)
            
            % Make the main window invisible
            app.SetupDataCleaningUIFigure.Visible = false;              
            
            % change folder into the selected one
            cd(app.UserworkfolderEditField.Value)
            
            % Save the parameters and used them for the next run as inital setup          
            inputInfo.Years                 = app.YearsEditField.Value;
            inputInfo.siteID                = app.SiteSelectionDropDown.Value;
            inputInfo.databaseServerPath    = app.DatabaseserverlocationEditField.Value;
            inputInfo.databaseLocalPath     = app.LocalcopyofdatabaseEditField.Value;
            inputInfo.workFolderPath        = app.UserworkfolderEditField.Value;  
            save('./localDataCleaning_ini.mat','inputInfo');
            
            try
                
                % Notify users that this program is does not copy Calculation_Procedures
                % files and folders
                h = msgbox({'This App will not update "Calculation_Procedures" folder.','Use "git -pull" instead.','','Press OK to continue.',''},'Important!');
                msgboxFontSize(h,app.defFontSize);
                uiwait(h,25)
                delete(h)
                
                localDatabaseFolder = app.LocalcopyofdatabaseEditField.Value;
                micrometDatabaseFolder = app.DatabaseserverlocationEditField.Value;
                siteID = app.SiteSelectionDropDown.Value;
                
                % First save the current input data for the future use
                
                % Copy one year at the time
                allYears = str2num(app.YearsEditField.Value); %#ok<ST2NM> 
                opts = struct('WindowStyle','replace','Default','','Interpreter','tex');   % options for warndlg box below
                h = warndlg('Copying ...','Progress',opts);
                msgboxFontSize(h,app.defFontSize);                  
                for yearToCopy = allYears
                    warndlg(sprintf('Copying %d files. This may take a few minutes. Please wait...',yearToCopy),'Progress',opts);
                    msgboxFontSize(h,app.defFontSize);
                    drawnow
                    if ispc
                        folderToCopy = fullfile(micrometDatabaseFolder,num2str(yearToCopy),siteID);
                        destinationFolder = fullfile(localDatabaseFolder,num2str(yearToCopy),siteID);                  
                    else
                        folderToCopy = fullfile(micrometDatabaseFolder,num2str(yearToCopy),siteID);
                        destinationFolder = fullfile(localDatabaseFolder,num2str(yearToCopy)); 
                    end 
                    cmdStr = sprintf(app.copyCMD,folderToCopy,destinationFolder);
                    [~,~]=system(cmdStr);
                end
                delete(h)

                
%  Removed Apr 14, 2023. Use GitHub to keep../Calculation_Procedures up to date. (Zoran)
%
%
%                 % Make sure there are "../Calculation_Procedures/TraceAnalysis_ini/siteID"
%                 %                 and "../Calculation_Procedures/TraceAnalysis_ini/siteID/Derived_Variables"
%                 % folder under localDatabaseFolder
%                 iniFilePath = fullfile(localDatabaseFolder,'Calculation_Procedures','TraceAnalysis_ini',siteID); 
%                 derivedVariablesPath = fullfile(iniFilePath,'Derived_Variables');
%                 if ~exist(derivedVariablesPath,'dir')
%                     mkdir(derivedVariablesPath);
%                 end
%                 
%                 % IMPORTANT: 
%                 %       Put your siteID_FirstStage.ini, siteID_SecondStage.ini, siteID_ThirdStage.ini files 
%                 %       in the folder: "../Calculation_Procedures/TraceAnalysis_ini/siteID".
%                 %       The next steps below will try to remind you to do so
%                 firstStageIniPath = fullfile(iniFilePath,[siteID '_FirstStage.ini']);
%                 secondStageIniPath = fullfile(iniFilePath,[siteID '_SecondStage.ini']);
%                 thirdStageIniPath = fullfile(iniFilePath,[siteID '_ThirdStage.ini']);          
%      
%                 % if any of the _FirstStage/_SecondStage/_ThirdStage ini files are missing in the local folder,
%                 % copy them from the server folder
%                 if ~exist(firstStageIniPath,'file') || ~exist(secondStageIniPath,'file') || ~exist(thirdStageIniPath,'file')
%                     % check which files are missing and copy only them
%                     sourceIniFilePath = fullfile(micrometDatabaseFolder,'Calculation_Procedures','TraceAnalysis_ini',siteID);                    
%                     safe_copyfile(app,fullfile(sourceIniFilePath,[siteID '_FirstStage.ini']), firstStageIniPath)
%                     safe_copyfile(app,fullfile(sourceIniFilePath,[siteID '_SecondStage.ini']),secondStageIniPath) 
%                     safe_copyfile(app,fullfile(sourceIniFilePath,[siteID '_ThirdStage.ini']), thirdStageIniPath)
%                     safe_copyfile(app,fullfile(sourceIniFilePath,[siteID '_ThirdStage_ini.R']), thirdStageIniPath)
%                 end
%                 
%                 %      Check if there are any files in Derived_variables
%                 %      and copy only the newer files from Derived_variables
%                 derivedVariablesServerPath = fullfile(micrometDatabaseFolder,'Calculation_Procedures','TraceAnalysis_ini',siteID,'Derived_Variables');
%                 s_server = dir(derivedVariablesServerPath);
%                 for cntFiles = 1:length(s_server)
%                     if ~s_server(cntFiles).isdir
%                         srcFileName = fullfile(s_server(cntFiles).folder,s_server(cntFiles).name);
%                         safe_copyfile(app,srcFileName, fullfile(derivedVariablesPath,s_server(cntFiles).name));
%                     end
%                 end
                
                % Create biomet_database_default that points to the target folder
                fid = fopen('biomet_database_default.m','wt');
                if fid <0
                    error('Could not create biomet_database_default.m in the folder: %s',pwd);
                end
                fprintf(fid,'function x = biomet_database_default\n');
                fprintf(fid,'x = ''%s'';\n',localDatabaseFolder);
                fclose(fid);            
                
                if exist('fig','var')
                    try
                        close(fig);
                        clear('fig')
                    catch
                    end
                end
                h = msgbox('Setup finished.','Finished!');
                msgboxFontSize(h,app.defFontSize);
                uiwait(h,5)
                delete(h)
                app.delete
            catch
                h = errordlg('Error while copying server data to local computer. Exiting.','Error');
                msgboxFontSize(h,app.defFontSize);
                uiwait(h,5)
                delete(h)
                app.SetupDataCleaningUIFigure.Visible = true;
            end
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete
        end

        % Drop down opening function: SiteSelectionDropDown
        function SiteSelectionDropDownOpening(app, event)
            all_folders=dir(fullfile(app.DatabaseserverlocationEditField.Value,app.YearsEditField.Value));
            all_folders(contains({all_folders.name},{'.','..'}))=[];
            p=find(vertcat(all_folders(:).isdir)==1);            
            app.SiteSelectionDropDown.Items={all_folders(p).name}; %#ok<FNDSB> 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetupDataCleaningUIFigure = uifigure('Visible', 'off');
            app.SetupDataCleaningUIFigure.Name = 'Setup Data Cleaning';
            app.DatabaseserverlocationEditFieldLabel = uilabel(app.SetupDataCleaningUIFigure);
            app.DatabaseserverlocationEditFieldLabel.Text = 'Database server location:';
            app.DatabaseserverlocationEditField = uieditfield(app.SetupDataCleaningUIFigure, 'text');
            app.BrowseDatabaseFolder = uibutton(app.SetupDataCleaningUIFigure, 'push');
            app.BrowseDatabaseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseDatabaseFolderButtonPushed, true);
            app.BrowseDatabaseFolder.Text = 'Browse';
            app.CopyDatabaseButton = uibutton(app.SetupDataCleaningUIFigure, 'push');
            app.CopyDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CopyDatabaseButtonPushed, true);
            app.CopyDatabaseButton.Text = 'Copy Database';
            app.CancelButton = uibutton(app.SetupDataCleaningUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BrowseLocalDatabaseFolder = uibutton(app.SetupDataCleaningUIFigure, 'push');
            app.BrowseLocalDatabaseFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseLocalDatabaseFolderButtonPushed, true);
            app.BrowseLocalDatabaseFolder.Text = 'Browse';
            app.LocalcopyofdatabaseEditFieldLabel = uilabel(app.SetupDataCleaningUIFigure);
            app.LocalcopyofdatabaseEditFieldLabel.Text = 'Local copy of database:';
            app.LocalcopyofdatabaseEditField = uieditfield(app.SetupDataCleaningUIFigure, 'text');
            app.SiteSelectionDropDownLabel = uilabel(app.SetupDataCleaningUIFigure);
            app.SiteSelectionDropDownLabel.Text = 'Site Selection';
            app.SiteSelectionDropDown = uidropdown(app.SetupDataCleaningUIFigure);
            app.SiteSelectionDropDown.Items = {'BB', 'BB2', 'DSM', 'RBM', 'Young', 'Hogg'};
            app.SiteSelectionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SiteSelectionDropDownOpening, true);
            app.SiteSelectionDropDown.Value = 'DSM';
            app.YearsEditFieldLabel = uilabel(app.SetupDataCleaningUIFigure);
            app.YearsEditFieldLabel.Text = 'Years:';
            app.YearsEditField = uieditfield(app.SetupDataCleaningUIFigure, 'text');
            app.SetupDataCleaningonLocalComputerLabel = uilabel(app.SetupDataCleaningUIFigure);
            app.SetupDataCleaningonLocalComputerLabel.Tooltip = {'This app sets up the necessary paths and copies database to the local computer. User can then run fr_automated_cleaning() from the current folder.'; ''};
            app.SetupDataCleaningonLocalComputerLabel.Text = 'Setup Data Cleaning on Local Computer';
            app.BrowseUserFolder = uibutton(app.SetupDataCleaningUIFigure, 'push');
            app.BrowseUserFolder.ButtonPushedFcn = createCallbackFcn(app, @BrowseUserFolderPushed, true);
            app.BrowseUserFolder.Text = 'Browse';
            app.UserworkfolderLabel = uilabel(app.SetupDataCleaningUIFigure);
            app.UserworkfolderLabel.Text = 'User work folder:';
            app.UserworkfolderEditField = uieditfield(app.SetupDataCleaningUIFigure, 'text');
            % Show the figure after all components are created
            app.SetupDataCleaningUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setupLocalDataCleaning

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SetupDataCleaningUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.SetupDataCleaningUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetupDataCleaningUIFigure)
        end
    end
end